classdef ADCS_program < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        logo_2                      matlab.ui.control.Image
        ActuatorsPanel              matlab.ui.container.Panel
        GridLayout8                 matlab.ui.container.GridLayout
        BrushlessMotorsButton       matlab.ui.control.Button
        RWConfigurationButton       matlab.ui.control.Button
        AttitudeDeterminationPanel  matlab.ui.container.Panel
        GridLayout2_2               matlab.ui.container.GridLayout
        AutoTuningButton            matlab.ui.control.Button
        DisableEKFCheckBox          matlab.ui.control.CheckBox
        Ekf_parameters_button       matlab.ui.control.Button
        sensors_button              matlab.ui.control.Button
        SimulationSettingsPanel     matlab.ui.container.Panel
        GridLayout5                 matlab.ui.container.GridLayout
        InitialTimeLabel_5          matlab.ui.control.Label
        InitialTimeLabel_4          matlab.ui.control.Label
        step_edit                   matlab.ui.control.NumericEditField
        InitialTimeLabel_3          matlab.ui.control.Label
        finalTime_edit              matlab.ui.control.NumericEditField
        initTime_edit               matlab.ui.control.NumericEditField
        text17_3                    matlab.ui.control.Label
        text17_2                    matlab.ui.control.Label
        text17                      matlab.ui.control.Label
        AttitudeControlPanel        matlab.ui.container.Panel
        GridLayout3                 matlab.ui.container.GridLayout
        AutotuningButton            matlab.ui.control.Button
        controller_button           matlab.ui.control.Button
        controller_popupmenu        matlab.ui.control.DropDown
        ModelSettingsPanel          matlab.ui.container.Panel
        GridLayout2                 matlab.ui.container.GridLayout
        MoreSettingsButton          matlab.ui.control.Button
        cubesat_button              matlab.ui.control.Button
        perturbation_button         matlab.ui.control.Button
        logo                        matlab.ui.control.Image
        run_button                  matlab.ui.control.Button
        uibuttongroup7              matlab.ui.container.ButtonGroup
        GridLayout6                 matlab.ui.container.GridLayout
        compCost_text               matlab.ui.control.Label
        ascct_text                  matlab.ui.control.Label
        eulerint_text               matlab.ui.control.Label
        settime_text                matlab.ui.control.Label
        text11                      matlab.ui.control.Label
        text10                      matlab.ui.control.Label
        text9                       matlab.ui.control.Label
        text8                       matlab.ui.control.Label
        uibuttongroup1              matlab.ui.container.ButtonGroup
        GridLayout4                 matlab.ui.container.GridLayout
        CubeSatAnimationLabel       matlab.ui.control.Label
        time_sLabel                 matlab.ui.control.Label
        TLabel                      matlab.ui.control.Label
        graphic2_run_button         matlab.ui.control.Button
        graphic2_stop_button        matlab.ui.control.Button
        graphic2_axes               matlab.ui.control.UIAxes
        uipanel1                    matlab.ui.container.Panel
        GridLayout7                 matlab.ui.container.GridLayout
        text2                       matlab.ui.control.Label
        graphic1_popupmenu          matlab.ui.control.DropDown
        UIAxes                      matlab.ui.control.UIAxes
        text16                      matlab.ui.control.Label
        ContextMenu                 matlab.ui.container.ContextMenu
        Menu2                       matlab.ui.container.Menu
    end

    
    properties (Access = private)
        callSatParameters  % Parametros para CubeSat
        callDisturbances   % 
        simulation         % Description
        rotBody            % Description
    end
    

    properties (Access = public)
       simulationParameters
    end
    

    methods (Access = private)
        function parameters = initializeSimulationParameters(~)
            %CubeSat Parameters
            parameters.initialValues.I = 1E-3*[3.084 0.082 -0.054;
                                               0.082 3.132 0.016;
                                              -0.054 0.016 3.540];
            parameters.initialValues.q0 =[1,0,0,0]';
            parameters.initialValues.Wo =[0,0,0]';
            %Algorithms Parameters
            parameters.sensors.gyro.Ts      = 5*0.001;
            parameters.sensors.attitude.Ts  = 10*0.001;
            parameters.control.Ts           = 5*0.001;
            %Sensors Parameters
            parameters.sensors.acc.bias  = [0.013127156, 0.010186677, 0.000138897]';
            parameters.sensors.acc.std   = [0.011817097, 0.012306208, 0.015949601]';
            parameters.sensors.mag.bias  = [0,0,0]';
            parameters.sensors.mag.std   = [0.011817097, 0.012306208, 0.015949601]';
            parameters.sensors.gyro.bias = [-0.0000541, -0.0001553, -0.00006458]';
            parameters.sensors.gyro.std  = [0.009,0.008,0.007]';
            parameters.sensors.star.bias = 1E-6*[1,1,1]';
            parameters.sensors.star.std  = 1E-3*[1,1,1]';
            parameters.sensors.star.enable = 1;
            parameters.sensors.star.numberOfStars = 10;
            %EKF Parameters
            parameters.ekf.equalModel = 1;
            parameters.ekf.enable     = 1;
            parameters.ekf.acc.std    = [0.011817097, 0.012306208, 0.015949601]';
            parameters.ekf.mag.std    = [0.011817097, 0.012306208, 0.015949601]';
            parameters.ekf.gyro.std   = [0.009,0.008,0.007]';
            parameters.ekf.star.std   = 1E-3*[1,1,1]';
            %Controller Parameters
            parameters.setPoint.qd =[0.9808,0.1691,0.0933,0.0277]';
            parameters.setPoint.Wd =[0,0,0]'; %rad/s
            %Controller Selector
            parameters.controller.selector = 1;
            %Feedback Controller parameters
            parameters.feedback.Keye = 0.2*eye(3);
            parameters.feedback.Peye = 0.3*eye(3);
            %Boskovic Controller parameters
            parameters.boskController.delta = 0.5;
            parameters.boskController.gamma = 0.001;
            parameters.boskController.Umax  = 0.10;
            parameters.boskController.k0    = 1;
            %Disturbances torque parameters
            parameters.disturbances.constant.Tdx = 0.001;
            parameters.disturbances.constant.Tdy = 0.001;
            parameters.disturbances.constant.Tdz = 0.001;
            parameters.disturbances.constant.enable = 1;   % 1-off, 2-on
            parameters.disturbances.variable.A   = 0.01;
            parameters.disturbances.variable.w1  = 1.00;
            parameters.disturbances.variable.w2  = 2.00;
            parameters.disturbances.variable.w3  = 3.00;
            parameters.disturbances.variable.enable = 1;   % 1-off, 2-on
            %Reaction Wheels Configuration
            parameters.rw.number = 3;
            parameters.rw.maxH   = 1; %Assuming all rw are equal
            parameters.rw.beta1  = 0;
            parameters.rw.beta2  = 0;
            parameters.rw.W      = eye(3);

            %Reaction Wheels as a DC motor
            parameters.rw.motor.kt   = 0.0254;
            parameters.rw.motor.Jrw  = 2.55E-05 + 9.25E-6;
            parameters.rw.motor.b    = 5.58E-4;
            parameters.rw.motor.c    = 0.0000;
            parameters.rw.motor.L    = 0.000403;
            parameters.rw.motor.R    = 1.16; 
            parameters.rw.motor.ke   = 0.0254; 

            %Reaction Wheels 
            parameters.rw.motor.pid.kp =   0.504519552012394;
            parameters.rw.motor.pid.ki =  28.7345189644697;
            parameters.rw.motor.pid.kd =  -0.000554999924740984;
            parameters.rw.motor.pid.Nu = 289.873462888377;
        end
        
        function validateData(~, time)
            if isnan(time.ti)
                errordlg('Invalid value of Initial Time.', 'Invalid Value', 'modal');
            elseif isnan(time.tf)
                errordlg('Invalid value of Final Time.', 'Invalid Value', 'modal');
            elseif isnan(time.step)
                errordlg('Invalid value of Step.', 'Invalid Value', 'modal');
            elseif time.ti >= time.tf
                errordlg('Initial Time must be lower than Final Time.', 'Invalid Value', 'modal');
            elseif time.step > (time.tf-time.ti)
                errordlg('Invalid value of step.', 'Invalid Value', 'modal');
            end
        end       

        function td_arr = getDisturbances(~, disturbances, time)
            %%%Recover time
            t = time.t;
            n = time.n;
            %%Create disturbances array
            Tdx = disturbances.constant.Tdx;
            Tdy = disturbances.constant.Tdy;
            Tdz = disturbances.constant.Tdz;
            Td_const_arr = repmat([Tdx,Tdy,Tdz],n,1)';
            A  = disturbances.variable.A;
            w1 = disturbances.variable.w1;
            w2 = disturbances.variable.w2;
            w3 = disturbances.variable.w3;
            td_misc_arr = A*sin([w1*t;w2*t;w3*t]);
            if     (disturbances.constant.enable == 2) && (disturbances.variable.enable == 2)
                td_arr = Td_const_arr+td_misc_arr;
            elseif (disturbances.constant.enable == 2) && (disturbances.variable.enable == 1)
                td_arr = Td_const_arr;
            elseif (disturbances.constant.enable == 1) && (disturbances.variable.enable == 2)
                td_arr = td_misc_arr;
            else
                td_arr = zeros(3,n);
            end
        end
        

        function [h1,h2] = plotVector(app,v0,v,scale,style,label)
            hold(app.graphic2_axes, 'on' );
            h1 = quiver3(app.graphic2_axes,v0(1)*scale, v0(2)*scale, v0(3)*scale, v(1)*scale, v(2)*scale, v(3)*scale, style);
            h2 = text(app.graphic2_axes,(v0(1)+v(1))*scale, (v0(2)+v(2))*scale, (v0(3)+v(3))*scale, label,'Color',style(1));
            set(h1,'Linewidth',2);
        end
    

        function rotX = quatRotation(~,q,x)
            qx = [0,x(1),x(2),x(3)];
            [a,b]=size(q);
            if a == 4 && b == 1
                q = q';
            end
            qrotX = quatmultiply(quatmultiply(q, qx), quatconj(q));
            rotX = qrotX(2:4);
        end


        function [hx, lx, hy, ly, hz, lz]= plotBodyframe(app)
            %%% Plot Body Frame
            q = app.simulationParameters.initialValues.q0;
            [hx,  lx]  = plotVector(app,zeros(1,3), app.quatRotation(q,[1,0,0]'),1, 'r--', 'X_B');
            [hy,  ly]  = plotVector(app,zeros(1,3), app.quatRotation(q,[0,1,0]'),1, 'r--', 'Y_B');
            [hz,  lz]  = plotVector(app,zeros(1,3), app.quatRotation(q,[0,0,1]'),1, 'r--', 'Z_B');
        end


        function updateBodyframe(~, B, hx, lx, hy, ly, hz,lz)
            set(hx, 'UData', B(1,1), 'VData', B(2,1), 'WData', B(3,1));      
            set(lx, 'Position', B(:,1));
            set(hy, 'UData', B(1,2), 'VData', B(2,2), 'WData', B(3,2));      
            set(ly, 'Position', B(:,2));
            set(hz, 'UData', B(1,3), 'VData', B(2,3), 'WData', B(3,3));      
            set(lz, 'Position', B(:,3));
        end

        
        function B = getpositionVector(app,x)
            B = cell(1,size(x,2));
            for i = 1:size(x,2)
                axisx = app.quatRotation(x(1:4,i),[1,0,0]');
                axisy = app.quatRotation(x(1:4,i),[0,1,0]');
                axisz = app.quatRotation(x(1:4,i),[0,0,1]');
                B{i} = [axisx',axisy',axisz'];
            end
        end
        
        function animation(app, B, hx, lx, hy, ly, hz, lz)
            % Inicializa el appdata
            setappdata(0, 'StopLoop', false);
            
            % Configura el callback del botón para cambiar el valor del appdata
            set(app.graphic2_stop_button, 'ButtonPushedFcn', @(src,event) setappdata(0, 'StopLoop', true));
            
            for i = 1:length(B)
                drawnow;
                if mod(i,round(length(B)/200))==0
                    app.updateBodyframe(B{i}, hx, lx, hy, ly, hz, lz);
                    app.time_sLabel.Text = [num2str(app.simulation.time.t(i),'%.3f'),' s'];
                    if getappdata(0, 'StopLoop')
                        break;
                    end
        
                end
                %pause(1E-4); 
            end
            app.graphic2_stop_button.Enable = "off";
            app.graphic2_run_button.Enable  = "on";
        end
    end

    methods (Access = public)
        
        function importData(app, receivedData)
           switch  receivedData.i
               case 1
                   %%%Update parameters 
                    app.simulationParameters.initialValues.I  = receivedData.I;
                    app.simulationParameters.initialValues.q0 = receivedData.q0';
                    app.simulationParameters.initialValues.Wo = receivedData.Wo';  
                    %%%Update animation
                    B = app.getpositionVector([receivedData.q0';0;0;0]);
                    app.updateBodyframe(B{1}, app.rotBody.hx, app.rotBody.lx, app.rotBody.hy, app.rotBody.ly, app.rotBody.hz,app.rotBody.lz);
                    %%%Show message
                    msgbox("Initial conditions successfully modified","Success");

               case 2
                    app.simulationParameters.disturbances.constant.Tdx = receivedData.Tdx;
                    app.simulationParameters.disturbances.constant.Tdy = receivedData.Tdy;
                    app.simulationParameters.disturbances.constant.Tdz = receivedData.Tdz;
                    app.simulationParameters.disturbances.constant.enable = receivedData.constantEnable;

                    app.simulationParameters.disturbances.variable.A   = receivedData.A;
                    app.simulationParameters.disturbances.variable.w1  = receivedData.w1;
                    app.simulationParameters.disturbances.variable.w2  = receivedData.w2;
                    app.simulationParameters.disturbances.variable.w3  = receivedData.w3;
                    app.simulationParameters.disturbances.variable.enable = receivedData.miscEnable;

                    msgbox("Disturbances parameter modified","Success");
               case 3
                    app.simulationParameters.sensors.acc.bias = receivedData.acc.bias';
                    app.simulationParameters.sensors.acc.std = receivedData.acc.std';
                    
                    app.simulationParameters.sensors.mag.bias = receivedData.mag.bias';
                    app.simulationParameters.sensors.mag.std = receivedData.mag.std';
                    
                    app.simulationParameters.sensors.gyro.bias = receivedData.gyro.bias';
                    app.simulationParameters.sensors.gyro.std = receivedData.gyro.std';
                    
                    app.simulationParameters.sensors.star.bias = receivedData.star.bias';
                    app.simulationParameters.sensors.star.std = receivedData.star.std';
                    app.simulationParameters.sensors.star.enable = receivedData.star.enable;
                    app.simulationParameters.sensors.star.numberOfStars = receivedData.star.numberOfStars;
                    
                    msgbox("Sensor parameters modified","Success");
               case 4
                    app.simulationParameters.setPoint.qd = receivedData.qd';
                    %app.simulationParameters.setPoint.Wd = receivedData.wd; %rad/s
                    app.simulationParameters.feedback.Keye = receivedData.K_eye;
                    app.simulationParameters.feedback.Peye = receivedData.P_eye;
                    msgbox("Controller parameters modified","Success");
               case 5
                    app.simulationParameters.setPoint.qd = receivedData.qd';
                    %app.simulationParameters.setPoint.Wd = receivedData.wd; %rad/s
                    app.simulationParameters.boskController.delta = receivedData.delta;
                    app.simulationParameters.boskController.gamma = receivedData.gamma;
                    app.simulationParameters.boskController.Umax  = receivedData.Umax;
                    app.simulationParameters.boskController.k0    = receivedData.k0;
                    msgbox("Controller parameters modified","Success");

               case 6
                    app.simulationParameters.ekf.equalModel = receivedData.ekf.equalModel;
                    if receivedData.ekf.equalModel == 0
                        app.simulationParameters.ekf.acc.std = receivedData.ekf.acc.std';
                        app.simulationParameters.ekf.mag.std = receivedData.ekf.mag.std';
                        app.simulationParameters.ekf.gyro.std = receivedData.ekf.gyro.std';
                        app.simulationParameters.ekf.star.std = receivedData.ekf.star.std';
                    else                       
                        app.simulationParameters.ekf.acc.std  = app.simulationParameters.sensors.acc.std;
                        app.simulationParameters.ekf.mag.std  = app.simulationParameters.sensors.acc.std;
                        app.simulationParameters.ekf.gyro.std = app.simulationParameters.sensors.acc.std;
                        app.simulationParameters.ekf.star.std = app.simulationParameters.sensors.acc.std;
                    end
                    msgbox("EKF parameters modified","Success");
               case 7
                   app.simulationParameters.rw.number = receivedData.rw.number;
                   app.simulationParameters.rw.maxH   = receivedData.rw.maxH;
                   app.simulationParameters.rw.beta1  = receivedData.rw.beta1;
                   app.simulationParameters.rw.beta2  = receivedData.rw.beta2;
                   app.simulationParameters.rw.W      = receivedData.rw.W;
                   msgbox("RW configuration parameters modified","Success");
               case 8
                   app.simulationParameters.control.Ts = receivedData.control.Ts;
                   app.simulationParameters.sensors.gyro.Ts = receivedData.sensors.gyro.Ts;
                   app.simulationParameters.sensors.attitude.Ts = receivedData.sensors.attitude.Ts;
                   msgbox("Sample times modified","Success");
               case 9
                   app.simulationParameters.rw.motor.kt   = receivedData.rw.motor.kt;
                   app.simulationParameters.rw.motor.Jrw  = receivedData.rw.motor.Jrw;
                   app.simulationParameters.rw.motor.b    = receivedData.rw.motor.b;
                   app.simulationParameters.rw.motor.c    = receivedData.rw.motor.c;
                   app.simulationParameters.rw.motor.L    = receivedData.rw.motor.L;
                   app.simulationParameters.rw.motor.R    = receivedData.rw.motor.R; 
                   app.simulationParameters.rw.motor.ke   = receivedData.rw.motor.ke; 

                   %Reaction Wheels 
                   app.simulationParameters.rw.motor.pid.kp =  receivedData.rw.motor.pid.kp;
                   app.simulationParameters.rw.motor.pid.ki =  receivedData.rw.motor.pid.ki;
                   app.simulationParameters.rw.motor.pid.kd =  receivedData.rw.motor.pid.kd;
                   app.simulationParameters.rw.motor.pid.Nu =  receivedData.rw.motor.pid.Nu;

                   msgbox("Brushless motor parameters modified","Success");
           end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.simulationParameters = initializeSimulationParameters(app);
           
            %%%Set plot
            axis(app.graphic2_axes, 'equal');
            app.graphic2_axes.XLim = [-1,1];
            app.graphic2_axes.YLim = [-1,1];
            app.graphic2_axes.ZLim = [-1,1];
            %%%Plot inertial Frame
            po = zeros(1,3);
            app.plotVector(po,[1,0,0],1,'b--','X_I');
            app.plotVector(po,[0,1,0],1,'b--','Y_I');
            app.plotVector(po,[0,0,1],1,'b--','Z_I');
            %%%Plot body Frame
            [hx, lx, hy, ly, hz,lz] = app.plotBodyframe();
            app.rotBody.hx = hx; app.rotBody.lx = lx;
            app.rotBody.hy = hy; app.rotBody.ly = ly;
            app.rotBody.hz = hz; app.rotBody.lz = lz;
        end

        % Button pushed function: cubesat_button
        function cubesat_buttonButtonPushed(app, event)
            app.callSatParameters = cubesatSettings(app,app.simulationParameters.initialValues);
        end

        % Button pushed function: perturbation_button
        function perturbation_buttonButtonPushed(app, event)
            app.callDisturbances = perturbationSettings(app,app.simulationParameters.disturbances);
        end

        % Button pushed function: controller_button
        function controller_buttonButtonPushed(app, event)
            v = app.controller_popupmenu.ValueIndex;
           
            switch v
                case 1
                    feedbackSettingd(app,app.simulationParameters.setPoint,app.simulationParameters.feedback);
                case 2
                    boskovicSettings(app,app.simulationParameters.setPoint,app.simulationParameters.boskController);
                otherwise
                    disp('other value')
            end
        end

        % Button pushed function: run_button
        function run_buttonButtonPushed(app, event)
            %%%Read time simulation Parameters
            time.ti   = app.initTime_edit.Value;
            time.tf   = app.finalTime_edit.Value;
            time.step = app.step_edit.Value;
            
            %%%Validate data 
            app.validateData(time);
            
            %%%Create time vector
            time.t=time.ti:time.step:time.tf;
            time.n=length(time.t);

            %%%Create disturbances array
            sim.td = app.getDisturbances(app.simulationParameters.disturbances, time);
            
            %%% 
            app.simulationParameters.controller.selector = app.controller_popupmenu.ValueIndex;

            %%%RunSimulation
            [sim.x, sim.u, sim.torque, sim.x_est, sim.index, sim.sensors, sim.actuators, sim.error_flag] = simulation_rk4(app,sim.td, ...
                app.simulationParameters, time);
                                  
            %%%Reset selector
            app.graphic1_popupmenu.Enable = "on";
            app.graphic1_popupmenu.ValueIndex = 1;
            
            %%%Plot euler angles by default
            plot(app.UIAxes,time.t, 180/pi*quat2eul(sim.x(1:4,:)')'); hold(app.UIAxes,'on');
            xlabel(app.UIAxes,'Time(s)'); ylabel(app.UIAxes,'Euler Angles(deg)');
            line(app.UIAxes,[sim.index.ts sim.index.ts], app.UIAxes.YLim, 'Color', 'r', 'LineWidth', 2);
            legend(app.UIAxes,'Roll','Pitch','Yaw','t_s');
            hold(app.UIAxes,'off');

            %%%turn into global
            app.simulation.results = sim;
            app.simulation.time = time;
            
            %%%Enable animation button
            app.graphic2_run_button.Enable = "on";
            
            %Update Controller performance parameters
            if sim.error_flag == 0
                app.settime_text.Text   = num2str(sim.index.ts,'%.2E');
                app.eulerint_text.Text  = num2str(sim.index.eulerInt(end-1),'%.2E');
                app.ascct_text.Text     = num2str(sim.index.ascct(end-1),'%.2E');
                app.compCost_text.Text  = num2str(mean(sim.index.o(2:end)),'%.2E');
            else
                app.settime_text.Text   = 'N/A';
                app.eulerint_text.Text  = 'N/A';
                app.ascct_text.Text     = 'N/A';
                app.compCost_text.Text  = 'N/A';

                warndlg('Unstable simulation', 'Simulation Error');
            end
        end

        % Value changed function: graphic1_popupmenu
        function graphic1_popupmenuValueChanged(app, event)
            value = app.graphic1_popupmenu.ValueIndex;
            num_rw = size(app.simulation.results.torque, 1);

            switch value
                case 1
                    plot(app.UIAxes,app.simulation.time.t, 180/pi*quat2eul(app.simulation.results.x(1:4,:)')');
                    %hold(app.UIAxes,'on');
                    %line(app.UIAxes,[app.simulation.results.ts app.simulation.results.ts], app.UIAxes.YLim, 'Color', 'r', 'LineWidth', 2);
                    xlabel(app.UIAxes,'Time(s)'); 
                    ylabel(app.UIAxes,'Euler Angles(deg)');
                    legend(app.UIAxes,'Roll','Pitch','Yaw','t_s');
                    hold(app.UIAxes,'off');

                case 2
                    plot(app.UIAxes,app.simulation.time.t,app.simulation.results.x(1:4,:))
                    xlabel(app.UIAxes,'Time(s)');
                    legend(app.UIAxes,'q0','q1','q2','q3');
                    ylabel(app.UIAxes,'Quaternions');
                case 3
                    plot(app.UIAxes, app.simulation.time.t,app.simulation.results.x(5:7,:))
                    xlabel(app.UIAxes,'Time(s)');
                    legend(app.UIAxes,'X','Y','Z');
                    ylabel(app.UIAxes,'Angular Rates Error(rad/s)');
                case 4
                    plot(app.UIAxes, app.simulation.time.t,app.simulation.results.td)
                    xlabel(app.UIAxes,'Time(s)');
                    legend(app.UIAxes,'X','Y','Z');
                    ylabel(app.UIAxes,'Disturbances Torques(Nm)');
                case 5
                    plot(app.UIAxes, app.simulation.time.t, app.simulation.results.u)
                    xlabel(app.UIAxes,'Time(s)');
                    legend(app.UIAxes,'X','Y','Z');
                    ylabel(app.UIAxes,'Torque Command(Nm)');
                case 6
                    plot(app.UIAxes, app.simulation.time.t, app.simulation.results.torque','--')
                    hold(app.UIAxes,'on');
                    plot(app.UIAxes, app.simulation.time.t, app.simulation.results.actuators.torque_real')

                    xlabel(app.UIAxes,'Time(s)');
                    %num_rw = size(app.simulation.results.torque, 1);
                    legend_labels = cell(1, 2*num_rw); % Pre-asigna un cell array para eficiencia
                    for i = 1:2*num_rw
                        if i < 4
                            legend_labels{i} = ['RW ', num2str(i)];
                        else
                            legend_labels{i} = ['Real torque RW ', num2str(i-num_rw)];
                        end
                    end
                    legend(app.UIAxes, legend_labels);
                    ylabel(app.UIAxes,'Control Torque(Nm)');
                    hold(app.UIAxes,'off');
                case 7
                    plot(app.UIAxes, app.simulation.time.t, app.simulation.results.actuators.w_cmd','--');
                    hold(app.UIAxes,'on');
                    plot(app.UIAxes, app.simulation.time.t, app.simulation.results.actuators.x_rw(1:2:end,:)');                 
                    xlabel(app.UIAxes,'Time(s)');
                    ylabel(app.UIAxes,'RW angular rates (rad/s)');
                    
                    legend_labels = cell(1, 2*num_rw); % Pre-asigna un cell array para eficiencia
                    for i = 1:2*num_rw
                        if i < 4
                            legend_labels{i} = ['RW ', num2str(i)];
                        else
                            legend_labels{i} = ['Set Point RW', num2str(i-num_rw)];
                        end
                    end
                    legend(app.UIAxes, legend_labels);
                    hold(app.UIAxes,'off');
                case 8
                    plot(app.UIAxes, app.simulation.time.t, app.simulation.results.actuators.x_rw(2:2:end,:)');
                    xlabel(app.UIAxes,'Time(s)');
                    ylabel(app.UIAxes,'RW angular rates currents (A)');
                    legend_labels = cell(1, num_rw);
                    for i = 1:num_rw
                        legend_labels{i} = ['RW ', num2str(i)];
                    end
                    legend(app.UIAxes, legend_labels);
                        
                case 9
                    plot(app.UIAxes, app.simulation.time.t, app.simulation.results.actuators.u_rw');
                    xlabel(app.UIAxes,'Time(s)');
                    ylabel(app.UIAxes,'RW angular rates voltage (V)');
                    legend_labels = cell(1, num_rw);
                    for i = 1:num_rw
                        legend_labels{i} = ['RW ', num2str(i)];
                    end
                    legend(app.UIAxes, legend_labels);

                case 10
                    plot(app.UIAxes, app.simulation.time.t(1:end-1), app.simulation.results.index.eulerInt)
                    xlabel(app.UIAxes,'Time(s)');
                    ylabel(app.UIAxes,'EULERINT (rad.s)');
                    legend(app.UIAxes,'eulerint');
                case 11
                    plot(app.UIAxes, app.simulation.time.t, app.simulation.results.index.ascct)
                    xlabel(app.UIAxes,'Time(s)');
                    ylabel(app.UIAxes,'ASCCT (Nm)');
                    legend(app.UIAxes,'ascct');
               case 12
                    plot(app.UIAxes, app.simulation.time.t(1:end-1), app.simulation.results.index.o)
                    xlabel(app.UIAxes,'Time(s)');
                    ylabel(app.UIAxes,'Computational cost <o> (s)');
                    legend(app.UIAxes,'o');
               
                otherwise
                    disp('other value')
            end
        end

        % Button pushed function: graphic2_run_button
        function graphic2_run_buttonButtonPushed(app, event)
            %%%Enable stop animation button
            app.graphic2_stop_button.Enable = "on";
            app.graphic2_run_button.Enable  = "off";
            %%%Run animation
            B = getpositionVector(app,app.simulation.results.x);
            %h = msgbox("Running Animation..", "Message");
            app.animation(B, app.rotBody.hx, app.rotBody.lx, app.rotBody.hy, app.rotBody.ly, app.rotBody.hz,app.rotBody.lz );
            %delete(h);
          
        end

        % Button pushed function: graphic2_stop_button
        function graphic2_stop_buttonButtonPushed(app, event)
            %%%Enable stop animation button
            
        end

        % Button pushed function: sensors_button
        function sensors_buttonButtonPushed(app, event)
            sensorsSettings(app,app.simulationParameters.sensors);
        end

        % Value changed function: DisableEKFCheckBox
        function DisableEKFCheckBoxValueChanged(app, event)
            value = app.DisableEKFCheckBox.Value;
            if value == 1
            %     app.Ekf_parameters_button.Enable = "off";
            %     app.sensors_button.Enable = "off";
                 app.simulationParameters.ekf.enable = 0;
            else
            %     app.Ekf_parameters_button.Enable = "on";
            %     app.sensors_button.Enable = "on"; 
                 app.simulationParameters.ekf.enable = 1;
            end
        end

        % Button pushed function: Ekf_parameters_button
        function Ekf_parameters_buttonButtonPushed(app, event)
            EKFevaluator(app,app.simulationParameters,app.simulation);
        end

        % Button pushed function: RWConfigurationButton
        function RWConfigurationButtonPushed(app, event)
            reactionWheelsConf(app,app.simulationParameters);
        end

        % Button pushed function: MoreSettingsButton
        function MoreSettingsButtonPushed(app, event)
            moreSettings(app,app.simulationParameters)
        end

        % Button pushed function: AutotuningButton
        function AutotuningButtonPushed(app, event)
            %%%Read controller selector
            v = app.controller_popupmenu.ValueIndex;
            app.simulationParameters.controller.selector = v;

            %%%Read time simulation Parameters
            time.ti   = app.initTime_edit.Value;
            time.tf   = app.finalTime_edit.Value;
            time.step = app.step_edit.Value;
            
            %%%Validate data 
            app.validateData(time);
            
            %%%Create time vector
            time.t=time.ti:time.step:time.tf;
            time.n=length(time.t);

            %%%Create disturbances array
            sim.td = app.getDisturbances(app.simulationParameters.disturbances, time);
            
            % Optimization fun
            optimal_gains = optimize_gains_ga(app,sim.td, ...
                app.simulationParameters, time);

            if v == 1
                % Updates the parameters in the application with the optimal results.
                % This allows the user to immediately use the new gains.
                app.simulationParameters.feedback.Peye = diag(optimal_gains(1:3));
                app.simulationParameters.feedback.Keye = diag(optimal_gains(4:6));
            else
                % Updates the parameters in the application with the optimal results.
                % This allows the user to immediately use the new gains.
                app.simulationParameters.boskController.delta = optimal_gains(1);
                app.simulationParameters.boskController.gamma = optimal_gains(2); 
                app.simulationParameters.boskController.k0    = optimal_gains(3);
            end
        end

        % Button pushed function: AutoTuningButton
        function AutoTuningButtonPushed(app, event)
            %%%Read time simulation Parameters
            time.ti   = app.initTime_edit.Value;
            time.tf   = app.finalTime_edit.Value;
            time.step = app.step_edit.Value;
            
            %%%Validate data 
            app.validateData(time);
            
            %%%Create time vector
            time.t=time.ti:time.step:time.tf;
            time.n=length(time.t);

            %%%Create disturbances array
            sim.td = app.getDisturbances(app.simulationParameters.disturbances, time);

            optimal_params = optimize_ekf_ga(app,sim.td, ...
                                           app.simulationParameters, time);

             % Update the parameters in the application with the found solution.
            app.simulationParameters.ekf.equalModel = 0;
            app.simulationParameters.ekf.gyro.std = optimal_params(1:3)';
            app.simulationParameters.ekf.acc.std = optimal_params(4:6)';
            app.simulationParameters.ekf.mag.std = optimal_params(7:9)';
            if app.simulationParameters.sensors.star.enable == 1
                app.simulationParameters.ekf.star.std = optimal_params(10:12)';
            end

        end

        % Button pushed function: BrushlessMotorsButton
        function BrushlessMotorsButtonPushed(app, event)
            reactionWheels_parameters(app,app.simulationParameters);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.text16 = uilabel(app.GridLayout);
            app.text16.Tag = 'text16';
            app.text16.Text = 'ADCSim: A CubeSat simulator';
            app.uipanel1 = uipanel(app.GridLayout);
            app.uipanel1.Title = 'Graph';
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout7 = uigridlayout(app.uipanel1);
            app.UIAxes = uiaxes(app.GridLayout7);
            title(app.UIAxes, 'Simulation Results')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.graphic1_popupmenu = uidropdown(app.GridLayout7);
            app.graphic1_popupmenu.Items = {'Euler Angles (ZYX)', 'Quaternions', 'Angular Rates', 'Perturbations', 'Torque command', 'Control Torque', 'Reaction Wheels rates', 'Reaction Wheels currents', 'Reaction Wheel voltages', 'EULERINT', 'ASCCT', 'O'};
            app.graphic1_popupmenu.ValueChangedFcn = createCallbackFcn(app, @graphic1_popupmenuValueChanged, true);
            app.graphic1_popupmenu.Tag = 'graphic1_popupmenu';
            app.graphic1_popupmenu.Enable = 'off';
            app.graphic1_popupmenu.Value = 'Euler Angles (ZYX)';
            app.text2 = uilabel(app.GridLayout7);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Select Parameter:';
            app.uibuttongroup1 = uibuttongroup(app.GridLayout);
            app.uibuttongroup1.Title = 'Animation';
            app.uibuttongroup1.Tag = 'uibuttongroup1';
            app.GridLayout4 = uigridlayout(app.uibuttongroup1);
            app.graphic2_axes = uiaxes(app.GridLayout4);
            xlabel(app.graphic2_axes, 'X')
            ylabel(app.graphic2_axes, 'Y')
            zlabel(app.graphic2_axes, 'Z')
            app.graphic2_axes.XLim = [-1 1];
            app.graphic2_axes.YLim = [-1 1];
            app.graphic2_axes.ZLim = [-1 1];
            app.graphic2_axes.Tag = 'graphic2_axes';
            app.graphic2_stop_button = uibutton(app.GridLayout4, 'push');
            app.graphic2_stop_button.ButtonPushedFcn = createCallbackFcn(app, @graphic2_stop_buttonButtonPushed, true);
            app.graphic2_stop_button.Tag = 'graphic2_button';
            app.graphic2_stop_button.Enable = 'off';
            app.graphic2_stop_button.Text = 'Stop';
            app.graphic2_run_button = uibutton(app.GridLayout4, 'push');
            app.graphic2_run_button.ButtonPushedFcn = createCallbackFcn(app, @graphic2_run_buttonButtonPushed, true);
            app.graphic2_run_button.Tag = 'graphic2_button';
            app.graphic2_run_button.Enable = 'off';
            app.graphic2_run_button.Text = 'Run';
            app.TLabel = uilabel(app.GridLayout4);
            app.TLabel.Text = 'T: ';
            app.time_sLabel = uilabel(app.GridLayout4);
            app.time_sLabel.Text = '0.00 s';
            app.CubeSatAnimationLabel = uilabel(app.GridLayout4);
            app.CubeSatAnimationLabel.Text = 'CubeSat Animation';
            app.uibuttongroup7 = uibuttongroup(app.GridLayout);
            app.uibuttongroup7.Title = 'Controller Performance';
            app.uibuttongroup7.Tag = 'uibuttongroup7';
            app.GridLayout6 = uigridlayout(app.uibuttongroup7);
            app.text8 = uilabel(app.GridLayout6);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Settlement time (5%):';
            app.text9 = uilabel(app.GridLayout6);
            app.text9.Tag = 'text9';
            app.text9.Text = 'EULERINT:';
            app.text10 = uilabel(app.GridLayout6);
            app.text10.Tag = 'text10';
            app.text10.Text = 'ASCCT:';
            app.text11 = uilabel(app.GridLayout6);
            app.text11.Tag = 'text11';
            app.text11.Text = 'Computational Cost (O):';
            app.settime_text = uilabel(app.GridLayout6);
            app.settime_text.Tag = 'settime_text';
            app.settime_text.Text = '0.0';
            app.eulerint_text = uilabel(app.GridLayout6);
            app.eulerint_text.Tag = 'eulerint_text';
            app.eulerint_text.Text = '0.0';
            app.ascct_text = uilabel(app.GridLayout6);
            app.ascct_text.Tag = 'ascct_text';
            app.ascct_text.Text = '0.0';
            app.compCost_text = uilabel(app.GridLayout6);
            app.compCost_text.Tag = 'compCost_text';
            app.compCost_text.Text = '0.0';
            app.run_button = uibutton(app.GridLayout, 'push');
            app.run_button.ButtonPushedFcn = createCallbackFcn(app, @run_buttonButtonPushed, true);
            app.run_button.Tag = 'run_button';
            app.run_button.Text = 'Run';
            app.logo = uiimage(app.GridLayout);
            app.logo.ImageSource = fullfile(pathToMLAPP, 'adcs.png');
            app.ModelSettingsPanel = uipanel(app.GridLayout);
            app.ModelSettingsPanel.Title = 'Model Settings';
            app.GridLayout2 = uigridlayout(app.ModelSettingsPanel);
            app.perturbation_button = uibutton(app.GridLayout2, 'push');
            app.perturbation_button.ButtonPushedFcn = createCallbackFcn(app, @perturbation_buttonButtonPushed, true);
            app.perturbation_button.Tag = 'perturbation_button';
            app.perturbation_button.Text = 'Perturbations Settings';
            app.cubesat_button = uibutton(app.GridLayout2, 'push');
            app.cubesat_button.ButtonPushedFcn = createCallbackFcn(app, @cubesat_buttonButtonPushed, true);
            app.cubesat_button.Tag = 'cubesat_button';
            app.cubesat_button.Text = 'CubeSat Parameters';
            app.MoreSettingsButton = uibutton(app.GridLayout2, 'push');
            app.MoreSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @MoreSettingsButtonPushed, true);
            app.MoreSettingsButton.Text = 'More Settings';
            app.AttitudeControlPanel = uipanel(app.GridLayout);
            app.AttitudeControlPanel.Title = 'Attitude Control';
            app.GridLayout3 = uigridlayout(app.AttitudeControlPanel);
            app.controller_popupmenu = uidropdown(app.GridLayout3);
            app.controller_popupmenu.Items = {'Feedbak Controller', 'Boskovic''s Controller'};
            app.controller_popupmenu.Tag = 'controller_popupmenu';
            app.controller_popupmenu.Value = 'Feedbak Controller';
            app.controller_button = uibutton(app.GridLayout3, 'push');
            app.controller_button.ButtonPushedFcn = createCallbackFcn(app, @controller_buttonButtonPushed, true);
            app.controller_button.Tag = 'controller_button';
            app.controller_button.Text = 'Settings';
            app.AutotuningButton = uibutton(app.GridLayout3, 'push');
            app.AutotuningButton.ButtonPushedFcn = createCallbackFcn(app, @AutotuningButtonPushed, true);
            app.AutotuningButton.Text = 'Auto tuning';
            app.SimulationSettingsPanel = uipanel(app.GridLayout);
            app.SimulationSettingsPanel.Title = 'Simulation Settings';
            app.GridLayout5 = uigridlayout(app.SimulationSettingsPanel);
            app.text17 = uilabel(app.GridLayout5);
            app.text17.Tag = 'text17';
            app.text17.Text = 's';
            app.text17_2 = uilabel(app.GridLayout5);
            app.text17_2.Tag = 'text17';
            app.text17_2.Text = 's';
            app.text17_3 = uilabel(app.GridLayout5);
            app.text17_3.Tag = 'text17';
            app.text17_3.Text = 's';
            app.initTime_edit = uieditfield(app.GridLayout5, 'numeric');
            app.initTime_edit.ValueDisplayFormat = '%.E';
            app.finalTime_edit = uieditfield(app.GridLayout5, 'numeric');
            app.finalTime_edit.ValueDisplayFormat = '%.E';
            app.finalTime_edit.Value = 30;
            app.InitialTimeLabel_3 = uilabel(app.GridLayout5);
            app.InitialTimeLabel_3.Text = 'Step:';
            app.step_edit = uieditfield(app.GridLayout5, 'numeric');
            app.step_edit.ValueDisplayFormat = '%.E';
            app.step_edit.Value = 0.0001;
            app.InitialTimeLabel_4 = uilabel(app.GridLayout5);
            app.InitialTimeLabel_4.Text = 'Final time:';
            app.InitialTimeLabel_5 = uilabel(app.GridLayout5);
            app.InitialTimeLabel_5.Text = 'Initial time:';
            app.AttitudeDeterminationPanel = uipanel(app.GridLayout);
            app.AttitudeDeterminationPanel.Title = 'Attitude Determination';
            app.GridLayout2_2 = uigridlayout(app.AttitudeDeterminationPanel);
            app.sensors_button = uibutton(app.GridLayout2_2, 'push');
            app.sensors_button.ButtonPushedFcn = createCallbackFcn(app, @sensors_buttonButtonPushed, true);
            app.sensors_button.Tag = 'perturbation_button';
            app.sensors_button.Text = 'Sensor Models';
            app.Ekf_parameters_button = uibutton(app.GridLayout2_2, 'push');
            app.Ekf_parameters_button.ButtonPushedFcn = createCallbackFcn(app, @Ekf_parameters_buttonButtonPushed, true);
            app.Ekf_parameters_button.Tag = 'cubesat_button';
            app.Ekf_parameters_button.Text = 'EKF Parameters';
            app.DisableEKFCheckBox = uicheckbox(app.GridLayout2_2);
            app.DisableEKFCheckBox.ValueChangedFcn = createCallbackFcn(app, @DisableEKFCheckBoxValueChanged, true);
            app.DisableEKFCheckBox.Text = 'Disable EKF';
            app.AutoTuningButton = uibutton(app.GridLayout2_2, 'push');
            app.AutoTuningButton.ButtonPushedFcn = createCallbackFcn(app, @AutoTuningButtonPushed, true);
            app.AutoTuningButton.Text = 'Auto Tuning';
            app.ActuatorsPanel = uipanel(app.GridLayout);
            app.ActuatorsPanel.Title = 'Actuators';
            app.GridLayout8 = uigridlayout(app.ActuatorsPanel);
            app.RWConfigurationButton = uibutton(app.GridLayout8, 'push');
            app.RWConfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @RWConfigurationButtonPushed, true);
            app.RWConfigurationButton.Text = 'RW Configuration';
            app.BrushlessMotorsButton = uibutton(app.GridLayout8, 'push');
            app.BrushlessMotorsButton.ButtonPushedFcn = createCallbackFcn(app, @BrushlessMotorsButtonPushed, true);
            app.BrushlessMotorsButton.Text = 'Brushless Motors';
            app.logo_2 = uiimage(app.GridLayout);
            app.logo_2.ImageSource = fullfile(pathToMLAPP, 'figs', 'adcs.png');
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.GridLayout5.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ADCS_program

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end