classdef EKFevaluator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout2                    matlab.ui.container.GridLayout
        GridLayout3                    matlab.ui.container.GridLayout
        UIAxes                         matlab.ui.control.UIAxes
        EKFPerformanceEvaluatorPanel   matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        MeasurementsButtonGroup        matlab.ui.container.ButtonGroup
        StarSensorButton               matlab.ui.control.RadioButton
        StarNDropDown                  matlab.ui.control.DropDown
        StarNDropDownLabel             matlab.ui.control.Label
        MagnetometerButton             matlab.ui.control.RadioButton
        AccelerometerButton            matlab.ui.control.RadioButton
        GyroscopeButton                matlab.ui.control.RadioButton
        BiasButtonGroup                matlab.ui.container.ButtonGroup
        ZButton                        matlab.ui.control.RadioButton
        YButton                        matlab.ui.control.RadioButton
        XButton                        matlab.ui.control.RadioButton
        QuaternionButtonGroup          matlab.ui.container.ButtonGroup
        q3Button                       matlab.ui.control.RadioButton
        q2Button                       matlab.ui.control.RadioButton
        q1Button                       matlab.ui.control.RadioButton
        q0Button                       matlab.ui.control.RadioButton
        Selectdatatoplot               matlab.ui.control.DropDown
        SelectdatatoplotDropDownLabel  matlab.ui.control.Label
        EKFparametersPanel             matlab.ui.container.Panel
        GridLayout                     matlab.ui.container.GridLayout
        star_sensor_std_Edit_z         matlab.ui.control.NumericEditField
        star_sensor_std_Edit_y         matlab.ui.control.NumericEditField
        star_sensor_std_Edit_x         matlab.ui.control.NumericEditField
        acc_std_Edit_z                 matlab.ui.control.NumericEditField
        acc_std_Edit_y                 matlab.ui.control.NumericEditField
        acc_std_Edit_x                 matlab.ui.control.NumericEditField
        StarSensorstdLabel             matlab.ui.control.Label
        AccelerometerstdLabel          matlab.ui.control.Label
        MagnetometerstdLabel           matlab.ui.control.Label
        GyroscopestdLabel              matlab.ui.control.Label
        mag_std_Edit_z                 matlab.ui.control.NumericEditField
        mag_std_Edit_y                 matlab.ui.control.NumericEditField
        mag_std_Edit_x                 matlab.ui.control.NumericEditField
        gyro_std_Edit_z                matlab.ui.control.NumericEditField
        gyro_std_Edit_y                matlab.ui.control.NumericEditField
        gyro_std_Edit_x                matlab.ui.control.NumericEditField
        ApplyButton                    matlab.ui.control.StateButton
        ZLabel                         matlab.ui.control.Label
        YLabel                         matlab.ui.control.Label
        XLabel                         matlab.ui.control.Label
        SameasSensorModelsCheckBox     matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        callMainApp % Description
        simulationResults % Description
        gyro_bias
    end
    
    methods (Access = private)
        
        function updateInterface(app, data, simulationResults)
            if data.ekf.equalModel == 0
                 app.acc_std_Edit_x.Value  = data.ekf.acc.std(1);
                 app.acc_std_Edit_y.Value  = data.ekf.acc.std(2);
                 app.acc_std_Edit_z.Value  = data.ekf.acc.std(3);
                 app.gyro_std_Edit_x.Value = data.ekf.gyro.std(1);
                 app.gyro_std_Edit_y.Value = data.ekf.gyro.std(2);
                 app.gyro_std_Edit_z.Value = data.ekf.gyro.std(3);
                 app.mag_std_Edit_x.Value  = data.ekf.mag.std(1);
                 app.mag_std_Edit_y.Value  = data.ekf.mag.std(2);
                 app.mag_std_Edit_z.Value  = data.ekf.mag.std(3);
                 app.star_sensor_std_Edit_x.Value = data.ekf.star.std(1);
                 app.star_sensor_std_Edit_y.Value = data.ekf.star.std(1);
                 app.star_sensor_std_Edit_z.Value = data.ekf.star.std(1);

                app.acc_std_Edit_x.Editable  = "on";
                app.acc_std_Edit_y.Editable  = "on";
                app.acc_std_Edit_z.Editable  = "on";
                app.gyro_std_Edit_x.Editable = "on";
                app.gyro_std_Edit_y.Editable = "on";
                app.gyro_std_Edit_z.Editable = "on";
                app.mag_std_Edit_x.Editable  = "on";
                app.mag_std_Edit_y.Editable  = "on";
                app.mag_std_Edit_z.Editable  = "on";
                app.star_sensor_std_Edit_x.Editable = "on";
                app.star_sensor_std_Edit_y.Editable = "on";
                app.star_sensor_std_Edit_z.Editable = "on";
            else
                app.acc_std_Edit_x.Value  = data.sensors.acc.std(1);
                app.acc_std_Edit_y.Value  = data.sensors.acc.std(2);
                app.acc_std_Edit_z.Value  = data.sensors.acc.std(3);
                app.gyro_std_Edit_x.Value = data.sensors.gyro.std(1);
                app.gyro_std_Edit_y.Value = data.sensors.gyro.std(2);
                app.gyro_std_Edit_z.Value = data.sensors.gyro.std(3);
                app.mag_std_Edit_x.Value  = data.sensors.mag.std(1);
                app.mag_std_Edit_y.Value  = data.sensors.mag.std(2);
                app.mag_std_Edit_z.Value  = data.sensors.mag.std(3);
                app.star_sensor_std_Edit_x.Value = data.sensors.star.std(1);
                app.star_sensor_std_Edit_y.Value = data.sensors.star.std(1);
                app.star_sensor_std_Edit_z.Value = data.sensors.star.std(1);

                app.acc_std_Edit_x.Editable  = "off";
                app.acc_std_Edit_y.Editable  = "off";
                app.acc_std_Edit_z.Editable  = "off";
                app.gyro_std_Edit_x.Editable = "off";
                app.gyro_std_Edit_y.Editable = "off";
                app.gyro_std_Edit_z.Editable = "off";
                app.mag_std_Edit_x.Editable  = "off";
                app.mag_std_Edit_y.Editable  = "off";
                app.mag_std_Edit_z.Editable  = "off";
                app.star_sensor_std_Edit_x.Editable = "off";
                app.star_sensor_std_Edit_y.Editable = "off";
                app.star_sensor_std_Edit_z.Editable = "off";
            end

            if isempty(simulationResults)
                app.EKFPerformanceEvaluatorPanel.Enable = "off";
            else
                app.EKFPerformanceEvaluatorPanel.Enable = "on";
                app.simulationResults = simulationResults;
                app.StarNDropDown.Items = string(1:1:data.sensors.star.numberOfStars);

                plot(app.UIAxes,app.simulationResults.time.t, app.simulationResults.results.x_est(1,:),'b','LineWidth', 1.5);
                hold(app.UIAxes,'on');
                plot(app.UIAxes,app.simulationResults.time.t, app.simulationResults.results.x(1,:),'r--', 'LineWidth', 1.5);
                xlabel(app.UIAxes,'Time (s)');
                ylabel(app.UIAxes,'q_0');
                legend(app.UIAxes,'Estimated q_0', 'Real q_0', 'Location', 'best');
                hold(app.UIAxes,'off');

            end
        end
        
        function newData = updateParameters(app)
            newData.ekf.acc.std(1)  = app.acc_std_Edit_x.Value;
            newData.ekf.acc.std(2)  = app.acc_std_Edit_y.Value;
            newData.ekf.acc.std(3)  = app.acc_std_Edit_z.Value;
            newData.ekf.gyro.std(1) = app.gyro_std_Edit_x.Value;
            newData.ekf.gyro.std(2) = app.gyro_std_Edit_y.Value;
            newData.ekf.gyro.std(3) = app.gyro_std_Edit_z.Value;
            newData.ekf.mag.std(1)  = app.mag_std_Edit_x.Value;
            newData.ekf.mag.std(2)  = app.mag_std_Edit_y.Value;
            newData.ekf.mag.std(3)  = app.mag_std_Edit_z.Value;
            newData.ekf.star.std(1) = app.star_sensor_std_Edit_x.Value;
            newData.ekf.star.std(2) = app.star_sensor_std_Edit_y.Value;
            newData.ekf.star.std(3) = app.star_sensor_std_Edit_z.Value;
            newData.ekf.equalModel  = app.SameasSensorModelsCheckBox.Value;

            newData.i = 6;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, mainAppData, simulationResults)
            app.callMainApp = mainApp;
            app.gyro_bias = mainAppData.sensors.gyro.bias;
            updateInterface(app, mainAppData, simulationResults);
        end

        % Value changed function: SameasSensorModelsCheckBox
        function SameasSensorModelsCheckBoxValueChanged(app, event)
            value = app.SameasSensorModelsCheckBox.Value;
            if value == 0
                app.acc_std_Edit_x.Editable = "on";
                app.acc_std_Edit_y.Editable = "on";
                app.acc_std_Edit_z.Editable = "on";
                app.gyro_std_Edit_x.Editable = "on";
                app.gyro_std_Edit_y.Editable = "on";
                app.gyro_std_Edit_z.Editable = "on";
                app.mag_std_Edit_x.Editable = "on";
                app.mag_std_Edit_y.Editable = "on";
                app.mag_std_Edit_z.Editable = "on";
                app.star_sensor_std_Edit_x.Editable = "on";
                app.star_sensor_std_Edit_y.Editable = "on";
                app.star_sensor_std_Edit_z.Editable = "on";
            else
                app.acc_std_Edit_x.Editable = "off";
                app.acc_std_Edit_y.Editable = "off";
                app.acc_std_Edit_z.Editable = "off";
                app.gyro_std_Edit_x.Editable = "off";
                app.gyro_std_Edit_y.Editable = "off";
                app.gyro_std_Edit_z.Editable = "off";
                app.mag_std_Edit_x.Editable = "off";
                app.mag_std_Edit_y.Editable = "off";
                app.mag_std_Edit_z.Editable = "off";
                app.star_sensor_std_Edit_x.Editable = "off";
                app.star_sensor_std_Edit_y.Editable = "off";
                app.star_sensor_std_Edit_z.Editable = "off";
            end
        end

        % Value changed function: ApplyButton
        function ApplyButtonValueChanged(app, event)
            dataToSend = updateParameters(app);
            importData(app.callMainApp, dataToSend);
            delete(app);
        end

        % Value changed function: Selectdatatoplot
        function SelectdatatoplotValueChanged(app, event)
            value = app.Selectdatatoplot.ValueIndex;
            switch value
                case 1
                    if app.q0Button.Value == 1
                        plot(app.UIAxes,app.simulationResults.time.t, app.simulationResults.results.x_est(1,:),'b','LineWidth', 1.5);
                        hold(app.UIAxes,'on');
                        plot(app.UIAxes,app.simulationResults.time.t, app.simulationResults.results.x(1,:),'r--', 'LineWidth', 1.5);
                        xlabel(app.UIAxes,'Time (s)');
                        ylabel(app.UIAxes,'q_0');
                        legend(app.UIAxes,'Estimated q_0', 'Real q_0', 'Location', 'best');
                        hold(app.UIAxes,'off');
                    elseif app.q1Button.Value == 1
                        plot(app.UIAxes,app.simulationResults.time.t, app.simulationResults.results.x_est(2,:),'b','LineWidth', 1.5);
                        hold(app.UIAxes,'on');
                        plot(app.UIAxes,app.simulationResults.time.t, app.simulationResults.results.x(2,:),'r--', 'LineWidth', 1.5);
                        xlabel(app.UIAxes,'Time (s)');
                        ylabel(app.UIAxes,'q_1');
                        legend(app.UIAxes,'Estimated q_1', 'Real q_1', 'Location', 'best');
                        hold(app.UIAxes,'off');
                    elseif app.q2Button.Value == 1
                        plot(app.UIAxes,app.simulationResults.time.t, app.simulationResults.results.x_est(3,:),'b','LineWidth', 1.5);
                        hold(app.UIAxes,'on');
                        plot(app.UIAxes,app.simulationResults.time.t, app.simulationResults.results.x(3,:),'r--', 'LineWidth', 1.5);
                        xlabel(app.UIAxes,'Time (s)');
                        ylabel(app.UIAxes,'q_2');
                        legend(app.UIAxes,'Estimated q_2', 'Real q_2', 'Location', 'best');
                        hold(app.UIAxes,'off');
                    elseif app.q3Button.Value == 1
                        plot(app.UIAxes,app.simulationResults.time.t, app.simulationResults.results.x_est(4,:),'b','LineWidth', 1.5);
                        hold(app.UIAxes,'on');
                        plot(app.UIAxes,app.simulationResults.time.t, app.simulationResults.results.x(4,:),'r--', 'LineWidth', 1.5);
                        xlabel(app.UIAxes,'Time (s)');
                        ylabel(app.UIAxes,'q_3');
                        legend(app.UIAxes,'Estimated q_3', 'Real q_3', 'Location', 'best');
                        hold(app.UIAxes,'off');
                    end
                   
                case 2
                     if app.XButton.Value == 1
                        plot(app.UIAxes,app.simulationResults.time.t, app.simulationResults.results.x_est(5,:),'b','LineWidth', 1.5);
                        hold(app.UIAxes,'on');
                        plot(app.UIAxes,app.simulationResults.time.t, app.gyro_bias(1)*...
                            ones(1,length(app.simulationResults.results.x(1,:))),'r--', 'LineWidth', 1.5);
                        xlabel(app.UIAxes,'Time (s)');
                        ylabel(app.UIAxes,'bias \omega_x');
                        legend(app.UIAxes,'Estimated bias \omega_x', 'Real bias \omega_x', 'Location', 'best');
                        hold(app.UIAxes,'off');
                     elseif app.YButton.Value == 1
                        plot(app.UIAxes,app.simulationResults.time.t, app.simulationResults.results.x_est(6,:),'b','LineWidth', 1.5);
                        hold(app.UIAxes,'on');
                        plot(app.UIAxes,app.simulationResults.time.t, app.gyro_bias(2)*...
                            ones(1,length(app.simulationResults.results.x(2,:))),'r--', 'LineWidth', 1.5);
                        xlabel(app.UIAxes,'Time (s)');
                        ylabel(app.UIAxes,'bias \omega_y');
                        legend(app.UIAxes,'Estimated bias \omega_y', 'Real bias \omega_y', 'Location', 'best');
                        hold(app.UIAxes,'off');
                     elseif app.ZButton.Value == 1 
                        plot(app.UIAxes,app.simulationResults.time.t, app.simulationResults.results.x_est(7,:),'b','LineWidth', 1.5);
                        hold(app.UIAxes,'on');
                        plot(app.UIAxes,app.simulationResults.time.t, app.gyro_bias(3)*...
                            ones(1,length(app.simulationResults.results.x(3,:))),'r--', 'LineWidth', 1.5);
                        xlabel(app.UIAxes,'Time (s)');
                        ylabel(app.UIAxes,'bias \omega_z');
                        legend(app.UIAxes,'Estimated bias \omega_z', 'Real bias \omega_z', 'Location', 'best');
                        hold(app.UIAxes,'off');
                     end

                case 3
                     if app.GyroscopeButton.Value == 1
                        n = length(app.simulationResults.results.sensors.meas(1:3,:)); 
                        plot(app.UIAxes,app.simulationResults.time.t(1:n), app.simulationResults.results.sensors.meas(1:3,:),'','LineWidth', 1.5);
                        xlabel(app.UIAxes,'Time (s)');
                        ylabel(app.UIAxes,'Gyroscope (rad/s)');
                        legend(app.UIAxes,'\omega_{meas}_x', '\omega_{meas}_y', '\omega_{meas}_z', ...
                            'Location', 'best');
                        hold(app.UIAxes,'off');

                     elseif app.AccelerometerButton.Value == 1
                        n = length(app.simulationResults.results.sensors.meas(4:6,:)); 
                        plot(app.UIAxes,app.simulationResults.time.t(1:n), app.simulationResults.results.sensors.meas(4:6,:),'','LineWidth', 1.5);
                        hold(app.UIAxes,'on');
                        plot(app.UIAxes,app.simulationResults.time.t(1:n), app.simulationResults.results.sensors.est(1:3,:),'--', 'LineWidth', 1.5);
                        xlabel(app.UIAxes,'Time (s)');
                        ylabel(app.UIAxes,'Accelerometer');
                        legend(app.UIAxes,'g_{meas}_x', 'g_{meas}_y', 'g_{meas}_z', ...
                            'g_{est}_x', 'g_{est}_y', 'g_{est}_z', 'Location', 'best');
                        hold(app.UIAxes,'off');

                     elseif app.MagnetometerButton.Value == 1
                        n = length(app.simulationResults.results.sensors.meas(7:9,:)); 
                        plot(app.UIAxes,app.simulationResults.time.t(1:n), app.simulationResults.results.sensors.meas(7:9,:),'','LineWidth', 1.5);
                        hold(app.UIAxes,'on');
                        plot(app.UIAxes,app.simulationResults.time.t(1:n), app.simulationResults.results.sensors.est(7:9,:),'--', 'LineWidth', 1.5);
                        xlabel(app.UIAxes,'Time (s)');
                        ylabel(app.UIAxes,'Magnetometer');
                        legend(app.UIAxes,'m_{meas}_x', 'm_{meas}_y', 'm_{meas}_z', ...
                            'm_{est}_x', 'm_{est}_y', 'm_{est}_z', 'Location', 'best');
                        hold(app.UIAxes,'off');
                    
                     elseif app.StarSensorButton.Value == 1
                        n = length(app.simulationResults.results.sensors.meas(7:9,:)); 
                        star_num = str2double(app.StarNDropDown.Value);

                        plot(app.UIAxes,app.simulationResults.time.t(1:n), app.simulationResults.results.sensors.meas((star_num*3-2)+9:(star_num*3)+9, :),'','LineWidth', 1.5);
                        hold(app.UIAxes,'on');
                        plot(app.UIAxes,app.simulationResults.time.t(1:n), app.simulationResults.results.sensors.est((star_num*3-2)+6:star_num*3+6,:),'--', 'LineWidth', 1.5);
                        xlabel(app.UIAxes,'Time (s)');
                        ylabel(app.UIAxes,['Star ', num2str(star_num)]);
                        legend(app.UIAxes,'star_{meas}_x', 'star_{meas}_y', 'star_{meas}_z', ...
                            'star_{est}_x', 'star_{est}_y', 'star_{est}_z', 'Location', 'best');
                        hold(app.UIAxes,'off');
        
                     end
                    
                case 4
                    plot(app.UIAxes,app.simulationResults.time.t, app.simulationResults.results.sensors.meas(4:6,:));
                case 5
                    plot(app.UIAxes,app.simulationResults.time.t, app.simulationResults.results.sensors.meas(7:end,:));

            end
        end

        % Selection changed function: QuaternionButtonGroup
        function QuaternionButtonGroupSelectionChanged(app, event)
            selectedButton = app.QuaternionButtonGroup.SelectedObject;

            if app.Selectdatatoplot.ValueIndex == 1
                val = selectedButton.Text;

                switch val
                case 'q0'
                    plot(app.UIAxes,app.simulationResults.time.t, app.simulationResults.results.x_est(1,:),'b','LineWidth', 1.5);
                    hold(app.UIAxes,'on');
                    plot(app.UIAxes,app.simulationResults.time.t, app.simulationResults.results.x(1,:),'r--', 'LineWidth', 1.5);
                    xlabel(app.UIAxes,'Time (s)');
                    ylabel(app.UIAxes,'q_0');
                    legend(app.UIAxes,'Estimated q_0', 'Real q_0', 'Location', 'best');
                    hold(app.UIAxes,'off');
                case 'q1'
                    plot(app.UIAxes,app.simulationResults.time.t, app.simulationResults.results.x_est(2,:),'b','LineWidth', 1.5);
                    hold(app.UIAxes,'on');
                    plot(app.UIAxes,app.simulationResults.time.t, app.simulationResults.results.x(2,:),'r--', 'LineWidth', 1.5);
                    xlabel(app.UIAxes,'Time (s)');
                    ylabel(app.UIAxes,'q_1');
                    legend(app.UIAxes,'Estimated q_1', 'Real q_1', 'Location', 'best');
                    hold(app.UIAxes,'off');
                case 'q2'
                    plot(app.UIAxes,app.simulationResults.time.t, app.simulationResults.results.x_est(3,:),'b','LineWidth', 1.5);
                    hold(app.UIAxes,'on');
                    plot(app.UIAxes,app.simulationResults.time.t, app.simulationResults.results.x(3,:),'r--', 'LineWidth', 1.5);
                    xlabel(app.UIAxes,'Time (s)');
                    ylabel(app.UIAxes,'q_2');
                    legend(app.UIAxes,'Estimated q_2', 'Real q_2', 'Location', 'best');
                    hold(app.UIAxes,'off');
                case 'q3'
                    plot(app.UIAxes,app.simulationResults.time.t, app.simulationResults.results.x_est(4,:),'b','LineWidth', 1.5);
                    hold(app.UIAxes,'on');
                    plot(app.UIAxes,app.simulationResults.time.t, app.simulationResults.results.x(4,:),'r--', 'LineWidth', 1.5);
                    xlabel(app.UIAxes,'Time (s)');
                    ylabel(app.UIAxes,'q_3');
                    legend(app.UIAxes,'Estimated q_3', 'Real q_3', 'Location', 'best');
                    hold(app.UIAxes,'off');
                end
            end
        end

        % Selection changed function: BiasButtonGroup
        function BiasButtonGroupSelectionChanged(app, event)
            selectedButton = app.BiasButtonGroup.SelectedObject;

            if app.Selectdatatoplot.ValueIndex == 2
                val = selectedButton.Text;

                switch val
                case 'X'
                    plot(app.UIAxes,app.simulationResults.time.t, app.simulationResults.results.x_est(5,:),'b','LineWidth', 1.5);
                    hold(app.UIAxes,'on');
                    plot(app.UIAxes,app.simulationResults.time.t, app.gyro_bias(1)*...
                            ones(1,length(app.simulationResults.results.x(1,:))),'r--', 'LineWidth', 1.5);
                    xlabel(app.UIAxes,'Time (s)');
                    ylabel(app.UIAxes,'bias \omega_x');
                    legend(app.UIAxes,'Estimated bias \omega_x', 'Real bias \omega_x', 'Location', 'best');
                    hold(app.UIAxes,'off');
                case 'Y'
                    plot(app.UIAxes,app.simulationResults.time.t, app.simulationResults.results.x_est(6,:),'b','LineWidth', 1.5);
                    hold(app.UIAxes,'on');
                    plot(app.UIAxes,app.simulationResults.time.t, app.gyro_bias(2)*...
                            ones(1,length(app.simulationResults.results.x(2,:))),'r--', 'LineWidth', 1.5);
                    xlabel(app.UIAxes,'Time (s)');
                    ylabel(app.UIAxes,'bias \omega_y');
                    legend(app.UIAxes,'Estimated bias \omega_y', 'Real bias \omega_y', 'Location', 'best');
                    hold(app.UIAxes,'off');
                case 'Z'
                    plot(app.UIAxes,app.simulationResults.time.t, app.simulationResults.results.x_est(7,:),'b','LineWidth', 1.5);
                    hold(app.UIAxes,'on');
                    plot(app.UIAxes,app.simulationResults.time.t, app.gyro_bias(3)*...
                            ones(1,length(app.simulationResults.results.x(3,:))),'r--', 'LineWidth', 1.5);
                    xlabel(app.UIAxes,'Time (s)');
                    ylabel(app.UIAxes,'bias \omega_z');
                    legend(app.UIAxes,'Estimated bias \omega_z', 'Real bias \omega_z', 'Location', 'best');
                    hold(app.UIAxes,'off');
                end
            end
        end

        % Selection changed function: MeasurementsButtonGroup
        function MeasurementsButtonGroupSelectionChanged(app, event)
            selectedButton = app.MeasurementsButtonGroup.SelectedObject;

            if app.Selectdatatoplot.ValueIndex == 3
                val = selectedButton.Text;
                switch val
                    case 'Gyroscope'
                        n = length(app.simulationResults.results.sensors.meas(1:3,:)); 
                        plot(app.UIAxes,app.simulationResults.time.t(1:n), app.simulationResults.results.sensors.meas(1:3,:),'','LineWidth', 1.5);
                        xlabel(app.UIAxes,'Time (s)');
                        ylabel(app.UIAxes,'Gyroscope (rad/s)');
                        legend(app.UIAxes,'\omega_{meas}_x', '\omega_{meas}_y', '\omega_{meas}_z', ...
                            'Location', 'best');
                        hold(app.UIAxes,'off');

                    case 'Accelerometer'
                        n = length(app.simulationResults.results.sensors.meas(4:6,:)); 
                        plot(app.UIAxes,app.simulationResults.time.t(1:n), app.simulationResults.results.sensors.meas(4:6,:),'','LineWidth', 1.5);
                        hold(app.UIAxes,'on');
                        plot(app.UIAxes,app.simulationResults.time.t(1:n), app.simulationResults.results.sensors.est(1:3,:),'--', 'LineWidth', 1.5);
                        xlabel(app.UIAxes,'Time (s)');
                        ylabel(app.UIAxes,'Accelerometer');
                        legend(app.UIAxes,'g_{meas}_x', 'g_{meas}_y', 'g_{meas}_z', ...
                            'g_{est}_x', 'g_{est}_y', 'g_{est}_z', 'Location', 'best');
                        hold(app.UIAxes,'off');  

                    case 'Magnetometer'
                        n = length(app.simulationResults.results.sensors.meas(7:9,:)); 
                        plot(app.UIAxes,app.simulationResults.time.t(1:n), app.simulationResults.results.sensors.meas(7:9,:),'','LineWidth', 1.5);
                        hold(app.UIAxes,'on');
                        plot(app.UIAxes,app.simulationResults.time.t(1:n), app.simulationResults.results.sensors.est(7:9,:),'--', 'LineWidth', 1.5);
                        xlabel(app.UIAxes,'Time (s)');
                        ylabel(app.UIAxes,'Magnetometer');
                        legend(app.UIAxes,'m_{meas}_x', 'm_{meas}_y', 'm_{meas}_z', ...
                            'm_{est}_x', 'm_{est}_y', 'm_{est}_z', 'Location', 'best');
                        hold(app.UIAxes,'off');

                    case 'Star Sensor'
                        n = length(app.simulationResults.results.sensors.meas(7:9,:)); 
                        star_num = str2double(app.StarNDropDown.Value);

                        plot(app.UIAxes,app.simulationResults.time.t(1:n), app.simulationResults.results.sensors.meas((star_num*3-2)+9:(star_num*3)+9, :),'','LineWidth', 1.5);
                        hold(app.UIAxes,'on');
                        plot(app.UIAxes,app.simulationResults.time.t(1:n), app.simulationResults.results.sensors.est((star_num*3-2)+6:star_num*3+6,:),'--', 'LineWidth', 1.5);
                        xlabel(app.UIAxes,'Time (s)');
                        ylabel(app.UIAxes,['Star ', num2str(star_num)]);
                        legend(app.UIAxes,'star_{meas}_x', 'star_{meas}_y', 'star_{meas}_z', ...
                            'star_{est}_x', 'star_{est}_y', 'star_{est}_z', 'Location', 'best');
                        hold(app.UIAxes,'off');

                end
            end
            
        end

        % Value changed function: StarNDropDown
        function StarNDropDownValueChanged(app, event)
            selectedButton = app.MeasurementsButtonGroup.SelectedObject;
            val1 = app.Selectdatatoplot.ValueIndex;
            val2 = selectedButton.Text;
            if val1 == 3 && strcmp(val2, 'Star Sensor')
                star_num = str2double(app.StarNDropDown.Value);
                n = length(app.simulationResults.results.sensors.meas(7:9,:)); 
                plot(app.UIAxes,app.simulationResults.time.t(1:n), app.simulationResults.results.sensors.meas((star_num*3-2)+9:(star_num*3)+9, :),'','LineWidth', 1.5);
                hold(app.UIAxes,'on');
                plot(app.UIAxes,app.simulationResults.time.t(1:n), app.simulationResults.results.sensors.est((star_num*3-2)+6:star_num*3+6,:),'--', 'LineWidth', 1.5);
                xlabel(app.UIAxes,'Time (s)');
                ylabel(app.UIAxes,['Star ', num2str(star_num)]);
                legend(app.UIAxes,'star_{meas}_x', 'star_{meas}_y', 'star_{meas}_z', ...
                    'star_{est}_x', 'star_{est}_y', 'star_{est}_z', 'Location', 'best');
                hold(app.UIAxes,'off');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout2 = uigridlayout(app.UIFigure);
            app.EKFparametersPanel = uipanel(app.GridLayout2);
            app.EKFparametersPanel.Title = 'EKF parameters';
            app.GridLayout = uigridlayout(app.EKFparametersPanel);
            app.SameasSensorModelsCheckBox = uicheckbox(app.GridLayout);
            app.SameasSensorModelsCheckBox.ValueChangedFcn = createCallbackFcn(app, @SameasSensorModelsCheckBoxValueChanged, true);
            app.SameasSensorModelsCheckBox.Text = 'Same as Sensor Models';
            app.SameasSensorModelsCheckBox.Value = true;
            app.XLabel = uilabel(app.GridLayout);
            app.XLabel.Text = 'X';
            app.YLabel = uilabel(app.GridLayout);
            app.YLabel.Text = 'Y';
            app.ZLabel = uilabel(app.GridLayout);
            app.ZLabel.Text = 'Z';
            app.ApplyButton = uibutton(app.GridLayout, 'state');
            app.ApplyButton.ValueChangedFcn = createCallbackFcn(app, @ApplyButtonValueChanged, true);
            app.ApplyButton.Text = 'Apply';
            app.gyro_std_Edit_x = uieditfield(app.GridLayout, 'numeric');
            app.gyro_std_Edit_y = uieditfield(app.GridLayout, 'numeric');
            app.gyro_std_Edit_z = uieditfield(app.GridLayout, 'numeric');
            app.mag_std_Edit_x = uieditfield(app.GridLayout, 'numeric');
            app.mag_std_Edit_y = uieditfield(app.GridLayout, 'numeric');
            app.mag_std_Edit_z = uieditfield(app.GridLayout, 'numeric');
            app.GyroscopestdLabel = uilabel(app.GridLayout);
            app.GyroscopestdLabel.Text = 'Gyroscope std:';
            app.MagnetometerstdLabel = uilabel(app.GridLayout);
            app.MagnetometerstdLabel.Text = 'Magnetometer std:';
            app.AccelerometerstdLabel = uilabel(app.GridLayout);
            app.AccelerometerstdLabel.Text = 'Accelerometer std:';
            app.StarSensorstdLabel = uilabel(app.GridLayout);
            app.StarSensorstdLabel.Text = 'Star Sensor std:';
            app.acc_std_Edit_x = uieditfield(app.GridLayout, 'numeric');
            app.acc_std_Edit_y = uieditfield(app.GridLayout, 'numeric');
            app.acc_std_Edit_z = uieditfield(app.GridLayout, 'numeric');
            app.star_sensor_std_Edit_x = uieditfield(app.GridLayout, 'numeric');
            app.star_sensor_std_Edit_y = uieditfield(app.GridLayout, 'numeric');
            app.star_sensor_std_Edit_z = uieditfield(app.GridLayout, 'numeric');
            app.EKFPerformanceEvaluatorPanel = uipanel(app.GridLayout2);
            app.EKFPerformanceEvaluatorPanel.Title = 'EKF Performance Evaluator';
            app.GridLayout4 = uigridlayout(app.EKFPerformanceEvaluatorPanel);
            app.SelectdatatoplotDropDownLabel = uilabel(app.GridLayout4);
            app.SelectdatatoplotDropDownLabel.Text = 'Select data to plot:';
            app.Selectdatatoplot = uidropdown(app.GridLayout4);
            app.Selectdatatoplot.Items = {'Quaternion', 'Bias', 'Sensors'};
            app.Selectdatatoplot.ValueChangedFcn = createCallbackFcn(app, @SelectdatatoplotValueChanged, true);
            app.Selectdatatoplot.Value = 'Quaternion';
            app.QuaternionButtonGroup = uibuttongroup(app.GridLayout4);
            app.QuaternionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @QuaternionButtonGroupSelectionChanged, true);
            app.QuaternionButtonGroup.Title = 'Quaternion';
            app.q0Button = uiradiobutton(app.QuaternionButtonGroup);
            app.q0Button.Text = 'q0';
            app.q0Button.Value = true;
            app.q1Button = uiradiobutton(app.QuaternionButtonGroup);
            app.q1Button.Text = 'q1';
            app.q2Button = uiradiobutton(app.QuaternionButtonGroup);
            app.q2Button.Text = 'q2';
            app.q3Button = uiradiobutton(app.QuaternionButtonGroup);
            app.q3Button.Text = 'q3';
            app.BiasButtonGroup = uibuttongroup(app.GridLayout4);
            app.BiasButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BiasButtonGroupSelectionChanged, true);
            app.BiasButtonGroup.Title = 'Bias';
            app.XButton = uiradiobutton(app.BiasButtonGroup);
            app.XButton.Text = 'X';
            app.XButton.Value = true;
            app.YButton = uiradiobutton(app.BiasButtonGroup);
            app.YButton.Text = 'Y';
            app.ZButton = uiradiobutton(app.BiasButtonGroup);
            app.ZButton.Text = 'Z';
            app.MeasurementsButtonGroup = uibuttongroup(app.GridLayout4);
            app.MeasurementsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MeasurementsButtonGroupSelectionChanged, true);
            app.MeasurementsButtonGroup.Title = 'Measurements';
            app.GyroscopeButton = uiradiobutton(app.MeasurementsButtonGroup);
            app.GyroscopeButton.Text = 'Gyroscope';
            app.GyroscopeButton.Value = true;
            app.AccelerometerButton = uiradiobutton(app.MeasurementsButtonGroup);
            app.AccelerometerButton.Text = 'Accelerometer';
            app.MagnetometerButton = uiradiobutton(app.MeasurementsButtonGroup);
            app.MagnetometerButton.Text = 'Magnetometer';
            app.StarNDropDownLabel = uilabel(app.MeasurementsButtonGroup);
            app.StarNDropDownLabel.Text = 'Star  N°:';
            app.StarNDropDown = uidropdown(app.MeasurementsButtonGroup);
            app.StarNDropDown.Items = {'1'};
            app.StarNDropDown.ValueChangedFcn = createCallbackFcn(app, @StarNDropDownValueChanged, true);
            app.StarNDropDown.Value = '1';
            app.StarSensorButton = uiradiobutton(app.MeasurementsButtonGroup);
            app.StarSensorButton.Text = 'Star Sensor';
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.UIAxes = uiaxes(app.GridLayout3);
            title(app.UIAxes, 'EKF Performance Evauator')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EKFevaluator(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end