classdef moreSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        AlgorithmsSampletimePanel   matlab.ui.container.Panel
        GridLayout                  matlab.ui.container.GridLayout
        SaveButton                  matlab.ui.control.Button
        OtherSensorssEditField      matlab.ui.control.NumericEditField
        OtherSensorsLabel           matlab.ui.control.Label
        GyroscopesEditField         matlab.ui.control.NumericEditField
        GyroscopesEditFieldLabel    matlab.ui.control.Label
        ControlalgorithmsEditField  matlab.ui.control.NumericEditField
        ControlalgorithmLabel       matlab.ui.control.Label
    end

    
    properties (Access = private)
        callMainApp % Description

    end
    
    methods (Access = private)
        
        function updateInterface(app, data)
            %UPDATEINTERFACE Populates UI fields from a data structure.
            %
            %   This function acts as a bridge from the backend data to the
            %   frontend UI. It takes a data structure containing simulation
            %   parameters and uses its values to update the corresponding
            %   edit fields in the App Designer interface. This is typically
            %   called when loading settings from a file or restoring defaults.
            %
            %   Inputs:
            %       app  - The main app object, containing UI components.
            %       data - A structure containing the parameters to display.
            %              It is expected to have the fields:
            %              data.control.Ts
            %              data.sensors.gyro.Ts
            %              data.sensors.attitude.Ts

            % Update the value of each UI edit field with data from the structure.
            app.ControlalgorithmsEditField.Value = data.control.Ts;
            app.GyroscopesEditField.Value        = data.sensors.gyro.Ts;
            app.OtherSensorssEditField.Value     = data.sensors.attitude.Ts;
        end

        function newData = updateParameters(app)
            %UPDATEPARAMETERS Collects UI field values into a data structure.
            %
            %   This function gathers the current values from various UI
            %   edit fields and organizes them into a structured format.
            %   This is useful for saving the current state of the UI or
            %   passing the user-defined parameters to another function or app.
            %
            %   An identifier index 'i' is added to the structure to help
            %   a receiving function (like a switch-case) identify the type
            %   of data being sent.
            %
            %   Inputs:
            %       app - The main app object, containing UI components.
            %
            %   Outputs:
            %       newData - A structure containing the values read from the UI.

            % Read the value from each UI edit field and assign it to the new data structure.
            newData.control.Ts = app.ControlalgorithmsEditField.Value;
            newData.sensors.gyro.Ts = app.GyroscopesEditField.Value;
            newData.sensors.attitude.Ts = app.OtherSensorssEditField.Value;
            
            % Assign an identifier for this data packet.
            newData.i = 8;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, mainAppData)
            %STARTUPFCN Initializes the app after component creation.
            %
            % This function is automatically executed when the app starts.
            % It is designed to be called by a main application, establishing
            % a parent-child relationship between the two windows.
            %
            % Its primary roles are:
            % 1. Store a handle to the main application ('mainApp') so this
            %    child window can communicate back to it (e.g., send updated
            %    parameters).
            % 2. Populate its own UI components with the initial data
            %     ('mainAppData') passed from the main app.
            %
            % Inputs:
            %  app         - The app object for this UI figure.
            %  mainApp     - A handle to the main application that launched this one.
            %  mainAppData - A data structure (e.g., a struct) containing the
            %                initial settings to display in this app's UI.

            % Store the handle to the main application in a property of this app.
            % This allows this app to call public methods of the main app later.
            app.callMainApp = mainApp;

            % Call a helper function to populate the UI fields (e.g., edit boxes,
            % sliders) with the initial data received from the main app.
            updateInterface(app, mainAppData);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            %SAVEBUTTONPUSHED Callback for the 'Save' or 'Apply' button.
            %
            %   This function orchestrates the process of saving the user's
            %   changes from this settings window and returning control to
            %   the main application. It performs three key actions:
            %   1. Gathers the current values from the UI components.
            %   2. Transmits this data back to the main application.
            %   3. Closes the current settings window.
            %
            %   Inputs:
            %       app   - The app object for the current UI figure.
            %       event - Event data (not used in this function, but
            %               required by the callback signature).

            % Step 1: Collect the user-modified parameters from the UI fields
            % into a structured variable.
            dataToSend = updateParameters(app);

            % Step 2: Send the collected data back to the main application
            % by calling its public 'importData' method.
            importData(app.callMainApp, dataToSend);

            % Step 3: Close and delete the current window, as its purpose
            % (configuring and saving settings) is complete.
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AlgorithmsSampletimePanel = uipanel(app.UIFigure);
            app.AlgorithmsSampletimePanel.Title = 'Algorithms Sample time';
            app.GridLayout = uigridlayout(app.AlgorithmsSampletimePanel);
            app.ControlalgorithmLabel = uilabel(app.GridLayout);
            app.ControlalgorithmLabel.Text = 'Control algorithm (s): ';
            app.ControlalgorithmsEditField = uieditfield(app.GridLayout, 'numeric');
            app.GyroscopesEditFieldLabel = uilabel(app.GridLayout);
            app.GyroscopesEditFieldLabel.Text = 'Gyroscope (s):';
            app.GyroscopesEditField = uieditfield(app.GridLayout, 'numeric');
            app.OtherSensorsLabel = uilabel(app.GridLayout);
            app.OtherSensorsLabel.Text = 'Other Sensors (s):';
            app.OtherSensorssEditField = uieditfield(app.GridLayout, 'numeric');
            app.SaveButton = uibutton(app.GridLayout, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = moreSettings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end