classdef reactionWheelsConf < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        OptimizeBetaAnglesButton       matlab.ui.control.Button
        PlotSatellieInertiaElipsoidButton  matlab.ui.control.Button
        ReactionWheelsParametersPanel  matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        MaxHNmsEditField               matlab.ui.control.NumericEditField
        MaxHLabel                      matlab.ui.control.Label
        SaveButton_2                   matlab.ui.control.Button
        ResetButton                    matlab.ui.control.Button
        Beta2degEditField              matlab.ui.control.NumericEditField
        Beta2degEditFieldLabel         matlab.ui.control.Label
        Beta1degEditField              matlab.ui.control.NumericEditField
        Beta1degEditFieldLabel         matlab.ui.control.Label
        NumberofRWEditField            matlab.ui.control.NumericEditField
        NumberofRWLabel                matlab.ui.control.Label
        PlotTorqueEnvelopeButton       matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        callMainApp % Description
        data
        PreviousRWCount
    end
    
    methods (Access = private)
        
        function updateInterface(app, data)
            app.NumberofRWEditField.Value = data.rw.number;
            app.MaxHNmsEditField.Value  = data.rw.maxH;
            app.Beta1degEditField.Value = data.rw.beta1 * 180/pi;
            app.Beta2degEditField.Value = data.rw.beta2 * 180/pi;
            app.data = data;
            app.PreviousRWCount = data.rw.number;
            app.data.rw.W_temp  = app.data.rw.W;
            app.data.scale = max(diag(data.initialValues.I));
        end
        
        function newData = updateParameters(app)
            %%% Update gyro data
            newData.rw.number = app.NumberofRWEditField.Value;
            newData.rw.maxH  = app.MaxHNmsEditField.Value;
            newData.rw.beta1 = deg2rad(app.Beta1degEditField.Value);
            newData.rw.beta2 = deg2rad(app.Beta2degEditField.Value);
            newData.rw.W     = app.data.rw.W;
            newData.i        = 7;
        end

        function [h1,h2] = plotVector(~, axesHandle, v0, v, scale, style, label)
            %PLOTVECTOR Plots a 3D vector with an optional label in the specified axes.
            %
            %   [H1, H2] = PLOTVECTOR(~, AXESHANGLE, V0, V, SCALE, STYLE, LABEL) draws a 3D vector
            %   in the specified AXESHANDLE. The vector starts from the scaled origin point
            %   (V0 * SCALE) and extends by the scaled vector (V * SCALE). It also adds a
            %   text label at the end of the vector.
            %
            %   Inputs:
            %     ~          - An ignored input argument. 
            %     axesHandle - The handle to the axes object where the vector will be plotted.
            %     v0         - A 1x3 numeric vector [x0, y0, z0] representing the base point
            %                  for the vector.
            %     v          - A 1x3 numeric vector [vx, vy, vz] representing the direction and
            %                  unscaled magnitude of the vector from its base point.
            %     scale      - A positive scalar value to proportionally scale both the starting
            %                  point and the vector components. The plotted vector will effectively
            %                  run from (V0 * SCALE) to ((V0 + V) * SCALE).
            %     style      - A character array specifying the line style and color for the vector
            %                  (e.g., 'r-', 'b--', 'k:'). The color part of 'style' is also used
            %                  for the text label's color.
            %     label      - A character array or string for the text label to be placed at
            %                  the end of the vector.
            %
            %   Outputs:
            %     h1    - The handle to the quiver3 object (the plotted vector).
            %     h2    - The handle to the text object (the vector's label).
            %
            %   Developed by Bespi123

            % Plot the 3D vector using quiver3.
            h1 = quiver3(axesHandle,v0(1)*scale, v0(2)*scale, v0(3)*scale, ...
                                v(1)*scale, v(2)*scale, v(3)*scale, style);
            % Add a text label at the end of the plotted vector.
            h2 = text(axesHandle,(v0(1)+v(1))*scale, (v0(2)+v(2))*scale, ...
                               (v0(3)+v(3))*scale, label,'Color',style(1));
            % Set the LineWidth property of the plotted vector (h1) to 2.
            set(h1,'Linewidth',2);
        end

        function Rz = rotationZ(~, theta)
            %ROTATIONZ Generates a 3x3 rotation matrix around the Z-axis.
            %
            %   Rz = ROTATIONZ(THETA) returns a 3x3 rotation matrix that represents
            %   a counter-clockwise rotation by an angle THETA (in radians) around
            %   the Z-axis.
            %
            %   Inputs:
            %     theta - The angle of rotation in radians.
            %
            %   Output:
            %     Rz    - A 3x3 rotation matrix.
            %
            
            % Construct the rotation matrix
            Rz = [cos(theta), -sin(theta), 0;
                  sin(theta),  cos(theta), 0;
                  0,           0,          1];
            
        end
        
        function Rx = rotationX(~,theta)
            %ROTATIONX Generates a 3x3 rotation matrix around the X-axis.
            %
            %   Rx = ROTATIONX(THETA) returns a 3x3 rotation matrix that represents
            %   a counter-clockwise rotation by an angle THETA (in radians) around
            %   the X-axis.
            %
            %   Inputs:
            %     theta - The angle of rotation in radians.
            %
            %   Output:
            %     Rx    - A 3x3 rotation matrix.
            %
            
            % Construct the rotation matrix
            Rx = [1,           0,           0;
                  0,  cos(theta), -sin(theta);
                  0,  sin(theta),  cos(theta)];
        end

        function plotWmatrix(app, axesHandle, W)
         %%%Set plot
            axis(axesHandle, 'equal');
            axesHandle.XLim = [-1,1];
            axesHandle.YLim = [-1,1];
            axesHandle.ZLim = [-1,1];
            %%%Plot inertial Frame
            po = zeros(1,3);
            for i = 1:size(W,2)
                hold(axesHandle, 'on');
                app.plotVector(axesHandle, po, W(:,i), 1, 'r-', ...
                    ['RW_',num2str(i)]);
            end
        end

        function W_temp = calculate_W_matriz(app, current_rw_count, beta_2_rad, beta_1_rad)          
            if current_rw_count == 3
                % For a 3-wheel configuration, orient a base matrix using rotations.
                % A combined rotation matrix 'R' is created from the beta angles.
                R = app.rotationX(beta_2_rad) * app.rotationZ(beta_1_rad);
                W = eye(3);

                % Apply the rotation to a predefined base configuration matrix.
                W_temp = R * W;
            else % Assume 4 wheels
                % For a 4-wheel configuration, build the matrix directly for a
                % standard pyramid setup based on the beta angles.
                
                % Pre-calculate sine and cosine for efficiency and readability.
                s1 = sin(beta_1_rad); c1 = cos(beta_1_rad);
                s2 = sin(beta_2_rad); c2 = cos(beta_2_rad);
                
                % Construct the 3x4 W matrix for the pyramid configuration.
                % Each column represents the spin axis unit vector for one wheel.
                W = [ c1*c2, -c1*c2, -c1*c2,  c1*c2;
                      s1*c2,  s1*c2, -s1*c2, -s1*c2;
                         -s2,     s2,    -s2,     s2];
                W_temp = W;
            end
        end

        function updateCalculationAndPlot(app)
            % 1. Read the current values from the UI controls.
            current_rw_count = app.NumberofRWEditField.Value;
            beta_1_rad = deg2rad(app.Beta1degEditField.Value);
            beta_2_rad = deg2rad(app.Beta2degEditField.Value);
            
            % 2. Logic to handle the "jump" between 3 and 4 wheel configurations.
            %    This adjusts the beta angles to sensible defaults when switching modes.
            if app.PreviousRWCount == 3 && current_rw_count == 4
                % If switching from 3 to 4 wheels, apply default pyramid angles if needed.
                if beta_2_rad == 0
                    % Force beta_2 to a default of 35 degrees.
                    app.Beta2degEditField.Value = 35;
                    beta_2_rad = deg2rad(35); % Update local var for immediate calculation.
                end
                if beta_1_rad == 0
                    % Force beta_1 to a default of 45 degrees for a symmetric setup.
                    app.Beta1degEditField.Value = 45;
                    beta_1_rad = deg2rad(45); % Update local var for immediate calculation.
                end
            elseif app.PreviousRWCount == 4 && current_rw_count == 3 && beta_2_rad ~= 0
                % If switching from 4 to 3 wheels, reset beta_2 to 0 as it is not used.
                app.Beta2degEditField.Value = 0;
                beta_2_rad = deg2rad(0); % Update local variable.
            end
            
            % 3. Calculate the orientation matrix by calling a helper function.
            app.data.rw.W_temp = app.calculate_W_matriz(current_rw_count, beta_2_rad, beta_1_rad);
            
            % 4. Update the plot.
            cla(app.UIAxes); % Clear the axes before drawing.
            plotWmatrix(app, app.UIAxes, app.data.rw.W_temp); % Call helper to draw the matrix.
            
            % 5. Store the current wheel count to detect changes in the next callback.
            app.PreviousRWCount = current_rw_count;
        end

        function [corners,nij] = get_momentum_envelope_corners(app, HmaxWheels, W)
            % get_momentum_envelope_corners: Calculates the corners and envelopment planes
            % for a torque-momentum envelope based on the given inputs taking into account no
            % coplanar reaction wheels and with simetrical saturation.
            % Inputs:
            %   - HmaxWheels: Vector of maximum torques for each wheel.
            %   - W: Matrix of wheel vectors.
            % Outputs:
            %   - corners: Structure containing the calculated corners.
            %   - nij: Structure containing the envelopment planes.
        
            %%%Function variables
            corners    = struct();      %%%Structure to store the envelope corners
            tempCorner = [];            %%%Temporary variable for positive plane
        
            %%%Get envelopment planes and planes name
            [~,nij] = app.get_momentum_envelope(HmaxWheels, W, eye(3,1));
            planes  = app.createPlanes(nij);
            field   =  fieldnames(nij);
        
            %%%Get direction vectors where envelope corners are located
            hmax  = app.combinations(HmaxWheels);
            v     = W*hmax';
            
            %%%Obtain envelope points in the v directions 
            for i=1:size(v,2)
                %%%Check if the v vector is null or 
                if norm(v(:,i)) == 0
                    continue;
                end
                %%%Get the angular momentum in the v direction and point
                [H,~] = app.get_momentum_envelope(HmaxWheels, W, v(:,i));
                p = H*v(:,i)/norm(v(:,i));
                %%%Get planes where point p belongs
                planeNumber = app.intersectedPlanes(planes,p);
                
                %%%Save points in 'corners' structure according to each plane name
                 for j = 1 : size(planeNumber,1)
                    name = field(ceil(planeNumber(j)/2));
                    if mod(planeNumber(j),2) == 0
                        if isfield (corners,['m_',name{1,1}])
                            tempCorner = corners.(['m_',name{1,1}]);
                        end
                        corners.(['m_',name{1,1}]) = [tempCorner,p];
                        tempCorner=[];                
                    else
                        if isfield (corners,[name{1,1}])
                            tempCorner = corners.([name{1,1}]);
                        end
                        corners.([name{1,1}]) = [tempCorner,p];
                        tempCorner=[];    
                     end
                 end             
            end     
            
            %%%Check corners structure to validate and determine corners envelope
            corners = app.fixCorners(HmaxWheels, W, corners);
            
            %%%Organize corners structure
            corners = app.organizeCornersStructure(corners);
        
        end
        
             
        function planes = createPlanes(~,nij)
            % createPlanes: Create plane equations from the given normal vectors 
            %               and points
            %   nij: Structure containing normal vectors and points for each plane
            
            % Get the field names of the structure
            field = fieldnames(nij);
        
            % Initialize planes array and parallel vector
            planes = zeros(2*numel(field),4);
            %v = zeros(3,2);
            
            % Index to build planes array
            idx = 1;
            
            % Iterate over each field (plane)
            for i = 1:numel(field)
                name = field(i);
                
                % Get the parallel vector from the structure
                %eval(['v = nij.', name{1,1}, '.mag;']);
                v = nij.(name{1,1}).mag;
                v(:,1)=v; v(:,2)=-v';
        
                % Get the points in the plane from the structure
                %eval(['x0 = nij.', name{1,1}, '.points;']);
                x0 = nij.(name{1,1}).points;
                
                % Calculate the value of d for each point
                for j = 1:size(x0, 2)
                    d = -v(:, j)' * x0(:, j);
                    plane = [v(:, j)', d];
                    planes(idx,:)=plane;
                    idx = idx + 1;
                end
                
                %v = [];
            end
        end
         
        function v = combinations(~,Hmax)
            %   v = combinations(Hmax) generates all possible combinations by
            %   replacing 1 with -1 in the input array Hmax. Each combination is
            %   multiplied by the original elements of Hmax.
            %
            %   Input:
            %       - Hmax: Input array
            %
            %   Output:
            %       - v: Combinations generated by replacing 0 with -1 and
            %            multiplying by the original elements of Hmax
        
            n = numel(Hmax);  % Number of elements in the array
        
            % Generate all possible combinations
            combinations = dec2bin(0:2^n-1)-'0';  % Convert decimal numbers to binary
        
            % Replace 0 with -1 in the combinations
            combinations(combinations == 0) = -1;
        
            % Multiply each combination by the original elements
            v = Hmax .* combinations;
        end
        
        function index = intersectedPlanes(~,planes, x0)
            %   index = intersectedPlanes(planes, x0) finds the indices of planes
            %   in the 'planes' array that are intersected by the point 'x0'. The
            %   intersection is determined by checking if the dot product of each
            %   plane with the extended point 'x0' is within a specified tolerance.
            %
            %   Inputs:
            %       - planes: Array of plane coefficients (each row represents a 
            %                 plane)
            %       - x0: Point to check for intersection with the planes
            %
            %   Output:
            %       - index: Indices of the intersected planes
        
            % Check tolerances
            tolerance = 1e-6;
        
            % Extend x0 with a '1' to include homogeneous coordinate
            x0 = [x0; 1];
        
            % Compute the dot product of planes with the extended x0
            res = planes * x0;
        
            % Find the indices where the absolute dot product is within tolerance
            index = find(abs(res) < tolerance);
        end
        
        function sortedPoints = orderPoints(~,points)
            % orderPoints: Orders a set of n points to form a plane shape.
            %              It checks if the points are coplanar and calculates the 
            %              angles between vectors. The points are ordered based on 
            %              the angles in a counterclockwise direction.
            %
            % Input:
            %   - points: 3xn matrix representing n points in 3D space.
            %
            % Output:
            %   - sortedPoints: 3xn matrix with the points ordered based on the 
            %                   angles.
                
            %%%Debug variable
            DEBUG = false;
            
            %%%Verify if points are complanar 
            is_coplanar = all(abs(dot(cross(points(:,2)-points(:,1), ...
                                            points(:,3)-points(:,1)),...
                                            points(:,4)-points(:,1))) < 1e9*eps);
            if is_coplanar
                %%allocate variable
                angles=zeros(size(points,2)-1,1);
                
                %%%Debug mode
                if DEBUG
                    % figure(); %#ok<UNRCH>
                    % plot3(points(1,1),points(2,1),points(3,1),'ro');
                    % hold on
                    % plot3(points(1,2),points(2,2),points(3,2),'bo');
                    % plot3(points(1,3),points(2,3),points(3,3),'go');
                    % plot3(points(1,4),points(2,4),points(3,4),'ko');
                end
                
                %%%Calculate centroid and vectors from centroid to each point
                centroid = mean(points, 2);
                vectors = points - centroid;
                
                %%%Debug mode
                if DEBUG
                     quiver3(centroid(1),centroid(2),centroid(3),vectors(1,1),...
                             vectors(2,1),vectors(3,1),'r'); %#ok<UNRCH>
                     quiver3(centroid(1),centroid(2),centroid(3),vectors(1,2),...
                             vectors(2,2),vectors(3,2),'b');
                     quiver3(centroid(1),centroid(2),centroid(3),vectors(1,3),...
                             vectors(2,3),vectors(3,3),'g');
                     quiver3(centroid(1),centroid(2),centroid(3),vectors(1,4),...
                             vectors(2,4),vectors(3,4),'k');
                end
                
                %%%Find  reference vector    
                for j = 2:size(vectors,2)
                    v1 = cross(vectors(:,1),vectors(:,j));
                    if norm(v1) > 1e9*eps
                        break;
                    end
                end
                
                %%%Debug mode
                if DEBUG
                     quiver3(centroid(1),centroid(2),centroid(3),v1(1),v1(2),...
                             v1(3),'k'); %#ok<UNRCH>
                end
                
                %%% Calculate angles between reference vector and other vectors
                for i = 2:size(points,2)
                    cross_prod = cross(vectors(:,1),vectors(:,i));
                    
                    %%%Debug mode
                    if DEBUG
                       quiver3(centroid(1),centroid(2),centroid(3),cross_prod(1),...
                                cross_prod(2),cross_prod(3),'c'); %#ok<UNRCH>
                    end
                    
                    angle = acos(dot(vectors(:,1), vectors(:,i)) / ...
                                        (norm(vectors(:,1)) * norm(vectors(:,i))));
                    
                    %%%Check the angle direction according to cross product
                    %%%with the reference vector
                    parallel = norm(cross(v1, cross_prod)) < 1e10*eps;
                    antiparallel = norm(v1/norm(v1) + cross_prod/norm(cross_prod))...
                                      < 1e10*eps;
                    is_antiparallel =  parallel && antiparallel; 
                   
                    if is_antiparallel
                        angles(i-1) = 2*pi-angle;
                    else 
                        angles(i-1) = angle;
                    end
                end
                
                %%Order angles acording to its angle
                [~, order] = sortrows(angles);   
                sortedPoints = points(:, [1;order+1]);
            else
                %%If points are no coplanars just return
                disp('Points are no coplanars.');
                sortedPoints=points;
            end
        end
        
        function points = checkCorners(~,pPoints,side1,side2) 
            % checkCorners: Between all the points that are over the envelope plane, 
            %               with this fuction we determine which points belongs to 
            %               the corner points of momentum envelope based on sorted 
            %               points and side vectors.
            %
            % Inputs:
            %   - sortedPoints: Sorted points in order over the plane
            %   - side1: First side vector
            %   - side2: Second side vector
            % Output:
            %   - points: Corner points of the envelope plane
            
            %%%Program variables
            points = [];
            %%%Debug variables
            DEBUG = false;
            
            %%%Debug
            if DEBUG
                % %%%Plot sorted points with red dots and labels
                % for pp=1:size(pPoints,2)                   %#ok<UNRCH>
                %     plot3(pPoints(1,pp),pPoints(2,pp),pPoints(3,pp),'ro');
                %     hold on;
                %     text(pPoints(1,pp),pPoints(2,pp),pPoints(3,pp),...
                %         num2str(pp));
                % end  
            end
            
            %%%Iterate over continuos pairs of points
            for i = 1 : size(pPoints,2)-1
                for j = i+1 : size(pPoints,2)           
                    %%%Create a vector between i and j points
                    v1=pPoints(:,i)-pPoints(:,j);
        
                    %%%Debug
                    if DEBUG
                        %%%Plot red arrow to represent v1
                        quiver3(pPoints(1,j),pPoints(2,j),pPoints(3,j),...
                                v1(1),v1(2),v1(3),'r'); %#ok<UNRCH>
                    end
                    
                    %%%Check if the norm of v1 matches side1 or side2 within a tolerance
                    if abs(norm(v1)-norm(side1))< 1E6*eps || ...
                                                abs(norm(v1)-norm(side2))< 1E6*eps
                        %%%Iterate over additional points
                        for k = j+1:size(pPoints,2)
                            %%%Create a vector between j and k points
                            v2=pPoints(:,k)-pPoints(:,j);
                            %%%Debug
                            if DEBUG
                                quiver3(pPoints(1,j),pPoints(2,j),...
                                    pPoints(3,j),v2(1),v2(2),v2(3),'g'); %#ok<UNRCH>
                            end
                            
                            %%%Check if the norm of v2 matches side1 or side2 within 
                            %%%a tolerance
                            if abs(norm(v2)-norm(side1))< 1E6*eps || ...
                                                abs(norm(v2)-norm(side2))< 1E6*eps
                                v3 = v1 + v2;                   %Sum of v1 and v2
                                p3 = v3 + pPoints(:,j);    %Obtain the fourth point of the corner
                                %%%Debug
                                if DEBUG
                                    % plot3(p3(1),p3(2),p3(3),'ko'); %#ok<UNRCH>
                                end
                                
                                 %%%Iterate over remaining points to find the matching 
                                 %%%corner point
                                for l=k+1:size(pPoints,2)
                                    if all(abs(pPoints(:,l)-p3)<1E6*eps)
                                        %%%If all conditions are met return the
                                        %%%points
                                        points=[pPoints(:,i),pPoints(:,j),...
                                              pPoints(:,k),pPoints(:,l)];
                                        break;
                                    end
                                end
                                if ~isempty(points)
                                    break;
                                end
                            end
                        end
                    end                                
                    if ~isempty(points)
                        break;
                    end
                end
                if ~isempty(points)
                    break;
                end
            end
            if DEBUG
                if ~isempty(points) %#ok<UNRCH>
                    % plot3(points(1,:),points(2,:),points(3,:),'go');
                end
                
            end
        end 

        function corners = fixCorners(app, Hmax, W, corners)
            % fixCorners: Fixes the corners of the momentum envelope if it has more
            %             than 4 points.
            % Inputs:
            %   - Hmax:  Vector of maximum torques for each wheel.
            %   - W:      Matrix of wheel vectors.
            %   - corners: Structure containing the corners of the envelope.
            %
            % Outputs:
            %   - corners: Updated structure with fixed corners.
            
            %%%Get corners field names
            field = fieldnames(corners);
            
            %%%Iterate over all elements of corners
            for i = 1:numel(field)
                name = field(i);    
                %%%Check if plane i has more than 4 'corners'
                if size(corners.(name{1,1}),2) ~= 4         
                    %%%Consider equal points with tolerance of 5 decimals
                    points = app.checkSamePoints(corners.(name{1,1}),5);
                    %%%Chek again if it has more tha 4 points
                    if size(points,2)==4
                        corners.(name{1,1})=points;
                        continue;
                    end
                    %%%Recover the facet edges vectors for the current plane 
                    side1=2*Hmax(str2double(name{1,1}(end)))*...
                            W(:,str2double(name{1,1}(end)));
                    side2=2*Hmax(str2double(name{1,1}(end-1)))*...
                            W(:,str2double(name{1,1}(end-1)));
                    %%%Sort points in counterclockwise direction
                    sortedPoints = app.orderPoints(points);
                    %%%Get corners for proposed plane corners  
                    corners.(name{1,1}) = app.checkCorners(sortedPoints,side1,side2);
                end
            end
        end
        
        function corners = organizeCornersStructure(~,corners)
            % organizeCornersStructure: Function to organize the corners structure 
            % by appending 'm_n' fields to corresponding 'n' fields.
            %
            % % Inputs:
            %   - corners: Structure containing the corners of the envelope.
            %
            % Outputs:
            %   - corners: Updated structure with well organized corners.
            
            %%%Create a copy of the corners structure
            cornerCpy = corners;
            %%%Get the field names of the corners structure
            field = fieldnames(corners);    
            
            %%%Iterate over each field in the corners structure
            for i = 1:numel(field)
                name = field(i);
                %%%Check if the field starts with 'n'
                if name{1,1}(1) == 'n'
                    %%%Append the corresponding 'm_n' field to the 'n' field
                    corners.(['n', name{1,1}(end-1:end)])=[corners.(['n', name{1,1}(end-1:end)]),...
                        cornerCpy.(['m_n', name{1,1}(end-1:end)])];
                %%%Check if the field starts with 'm'
                elseif name{1,1}(1) == 'm'
                    %%%Remove the 'm' field from the corners structure
                    corners = rmfield(corners, name{1,1});
                end
            end
        end

        function h1 = plotEnvelopment(app,nij,corners)
        %   plotEnvelopment: plots the faces of the momentum envelopment using
        %   the provided normal vectors (nij) and corner points (corners). The
        %   envelope is plotted for the specified planeName.
        %
        %   Inputs:
        %       - nij: Structure containing the normal vectors for each plane
        %
        %       -corners: Structure containing the corner points for each plane     
        
        %%%Plot unitary vectors
        field = fieldnames(nij);
        
        %%%Iterate over each field (plane)
        for i = 1:numel(field)
            name = field(i);
            %eval(['a=size(corners.',name{1,1},',2);']);
            a = size(corners.(name{1,1}),2);
                        
            %%%Rejec if corners are not completed
            if  (a ~= 8)
                continue;
            end
            
            %%%Iterate over two sets of corners to form the envelopment
            for l = [1, 5]
                %%%Extract the points from the corners structure
                %eval(['points = corners.',name{1,1},'(:,l:l+3);']);
                points = corners.(name{1,1})(:,l:l+3);
                %%%Sort in clockwise order
                sortedPoints = app.orderPoints(points);           
                %%%Plot the envelopment by filling the polygon formed by the sorted points
                h1 = fill3(app.UIAxes,sortedPoints(1,:), sortedPoints(2,:), sortedPoints(3,:), 'r', 'FaceAlpha', 0.1);
                %%%Hold on to plot multiple envelopments
                hold(app.UIAxes, 'on');
            end
        end
        end

        function [H,nij] = get_momentum_envelope(~, HmaxWheels, W, v)
        %   [H, nij] = get_momentum_envelope(HmaxWheels, W, v) calculates
        %   the momentum envelope in te v direction given the maximum angular  
        %   momentum of the wheels (HmaxWheels) and the wheel configuration 
        %   matrix (W). The function returns the momentum in the v direction(H) 
        %   and the plane normal vectors (nij) that define the envelope. This
        %   function is based on: 
        %   [1] Markley, F. Landis, et al. "Maximum torque and momentum envelopes
        %       for reaction wheel arrays." Journal of Guidance, Control, and 
        %       Dynamics 33.5 (2010): 1606-1614.
        %   [2] Yoon, Hyosang. "Maximum reaction-wheel array torque/momentum 
        %       envelopes for general configurations." Journal of Guidance, Control
        %       , and Dynamics 44.6 (2021): 1219-1223.
        %       https://github.com/HyosangYoon/MaximumTorqueEnvelope/tree/main
        %   [3] Yoon, Hyungjoo, Hyun Ho Seo, and Hong-Taek Choi. "Optimal uses
        %       of reaction wheels in the pyramid configuration using a new 
        %       minimum infinity-norm solution." Aerospace Science and Technology
        %       39 (2014): 109-119.
        %
        %   Inputs:
        %       - HmaxWheels: Maximum torques of the wheels (1 x n)
        %       - W: Wheel configuration matrix (3 x n)
        %       - v: Momentum direction (3 x 1)
        %
        %   Outputs:
        %       - H: Momentum obtained in the v direcction
        %       - nij: Structure with the plane normal vectors that define the 
        %              torque-momentum envelope.
        %  
        %   Example:
        %               Hmax = [1,1,1,1];
        %               beta=deg2rad(35.26);
        %               W=[sin(beta),sin(beta),sin(beta),sin(beta);0,cos(beta),0,-cos(beta);cos(beta),0,-cos(beta),0];
        %     
        %               %Draw the torque envelopes
        %               [X,Y,Z] = sphere(64);
        %               N = size(X,1);
        %               for i = 1:1:N
        %                 for j = 1:1:N
        %                     v = [X(i,j);Y(i,j);Z(i,j)];
        %                     v = v/norm(v);
        %                              
        %                     [H,~] = get_torque_momentum_envelope(Hmax, W, v);
        %                     v1 = H * v;
        %                     X(i,j) = v1(1);
        %                     Y(i,j) = v1(2);
        %                     Z(i,j) = v1(3);
        %                 end
        %             end
        %             
        %             figure
        %             surf(X,Y,Z, 'EdgeColor', 'k','FaceAlpha',0.1,'FaceColor', 'w');
        %             axis equal
        %             xlabel('X');
        %             ylabel('Y');
        %             zlabel('Z');
        %             hold on
    
        %%%Program variables
        n = size(W, 2);     % Number of reaction Wheels 
        v = v/norm(v);      % Normalize the momentum direction
        k = 1;

        %%% Pre-allocation for efficiency
        num_pairs   = n*(n-1)/2;
        h_candidates = NaN(num_pairs, 1);
        
        for i = 1:n-1
             for j = i+1:n                
                %%%Calculate parallel vector to plane
                planeNormal  = cross(W(:,i), W(:,j));     %Eq(4) 
                nij1 = planeNormal / norm(planeNormal);
    
                %%%Store the magnitude of the plane normal vector
                nij.(['n', num2str(i), num2str(j)]).mag = nij1;
    
                %%% Get indices of all other wheels (the saturated ones)
                mask = true(1,n);
                mask([i, j]) = false;
    
                %%% Calculate momentum h in nij direction        
                sigmak = sign(W(:, mask)' * planeNormal); %Eq(6a)
                h = sigmak .* HmaxWheels(mask)' .* (W(:, mask)'* nij1); %Eq(5)
                
                %%%Calculate the eight points which define the plane
                ck = [sum(h) * nij1, -1*sum(h) * nij1];  %% get z components
                ci =  HmaxWheels(i) * W(:,i); %% When the i-th rw is saturated
                cj =  HmaxWheels(j) * W(:,j); %% When the j-th rw is saturated   
                
                %%%Store the asociated points
                nij.(['n', num2str(i), num2str(j)]).points = [ck(:,1)+ci+cj,ck(:,2)+ci+cj];

                %%%Calculate h in v direction
                
                 if dot (nij1 , v) > eps
                    h_candidates(k) = sum(h)/dot(nij1,v);
                 elseif dot(nij1, v) < -eps
                    h_candidates(k) = -sum(h)/dot(nij1,v);
                 else
                     % If the plane is parallel to the momentum direction, it cannot be 
                     % the bounding facet.
                     continue;
                 end     
                 %%% Update k counter
                 k = k+1;
             end
        end
        %%% Find the minimum momentum
        H = min(h_candidates, [], 'omitnan');
        end
      
        
        function points = checkSamePoints(~, points, tol)
            % checkSamePoints: Remove duplicate columns from a matrix of points
            %   points: Input matrix of points (each column represents a point)
            %   tol: Tolerance for rounding the points
        
            % Round the points
            P_round = round(points, tol);
            
            % Get the unique columns
            [~, unique_cols, ~] = unique(P_round', 'rows');
            
            % Keep only the unique columns
            points = points(:, unique_cols);
        end

        function plotSatelliteMomentumElipsoid(~, data, axes, omega)     
            % Get the 3x3 inertia matrix from the input data structure.
            I_body = data.initialValues.I;
            
            % Create a grid of points for a unit sphere using spherical coordinates.
            % u represents the azimuthal angle (0 to 2*pi).
            % v represents the polar angle (0 to pi).
            [u, v] = meshgrid(linspace(0, 2*pi, 50), linspace(0, pi, 25));
        
            % Calculate the points of the ellipsoid surface.
            % NOTE: This calculation is only accurate if the inertia matrix I_body is
            % diagonal (i.e., the body frame is aligned with the principal axes).
            x = omega * I_body(1,1) * sin(v) .* cos(u);
            y = omega * I_body(2,2) * sin(v) .* sin(u);
            z = omega * I_body(3,3) * cos(v);
            
            % Draw the ellipsoid surface on the specified axes.
            % 'FaceAlpha' is set to a low value to make the surface semi-transparent.
            surf(axes, x, y, z, 'FaceColor', 'blue', 'EdgeColor', 'flat', 'FaceAlpha', 0.05);
            
            % Ensure that the scaling is the same for all axes to prevent visual distortion.
            axis(axes, 'equal');
            
            % Label the axes to correspond to the satellite's body frame.
            xlabel(axes, 'X_b');
            ylabel(axes, 'Y_b');
            zlabel(axes, 'Z_b');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, mainAppData)
            app.callMainApp = mainApp;
            updateInterface(app, mainAppData);
            
            %%Plot RW array
            plotWmatrix(app, app.UIAxes, app.data.rw.W);
        end

        % Value changed function: Beta1degEditField
        function Beta1degEditFieldValueChanged(app, event)
            updateCalculationAndPlot(app)
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            updateInterface(app, app.data);

            %%Plot RW array
            cla(app.UIAxes);
            plotWmatrix(app, app.UIAxes, app.data.rw.W);
        end

        % Button pushed function: SaveButton_2
        function SaveButton_2Pushed(app, event)
            app.data.rw.W = app.data.rw.W_temp;
            dataToSend = updateParameters(app);
            importData(app.callMainApp, dataToSend);
            delete(app);
        end

        % Value changed function: Beta2degEditField
        function Beta2degEditFieldValueChanged(app, event)
           updateCalculationAndPlot(app)
        end

        % Value changed function: NumberofRWEditField
        function NumberofRWEditFieldValueChanged(app, event)
            updateCalculationAndPlot(app)
        end

        % Button pushed function: PlotTorqueEnvelopeButton
        function PlotTorqueEnvelopeButtonPushed(app, event)
            W    = app.data.rw.W_temp;
            maxMomentum = app.MaxHNmsEditField.Value;
            Hmax = ones(1,length (W))*maxMomentum;

            %%Plot RW array
            cla(app.UIAxes);
            plotWmatrix(app, app.UIAxes, W);
            [corners,nij]=app.get_momentum_envelope_corners(Hmax,W);
            app.plotEnvelopment(nij,corners);
            app.UIAxes.XLimMode = 'auto';
            app.UIAxes.YLimMode = 'auto';
            app.UIAxes.ZLimMode = 'auto';
        end

        % Button pushed function: PlotSatellieInertiaElipsoidButton
        function PlotSatellieInertiaElipsoidButtonPushed(app, event)
            omega = 1/max(app.data.initialValues.I(:));
            cla(app.UIAxes);
            app.plotSatelliteMomentumElipsoid(app.data, app.UIAxes, omega);
            hold(app.UIAxes, 'on');
            app.plotWmatrix(app.UIAxes, app.data.rw.W_temp);
            app.UIAxes.XLimMode = 'auto';
            app.UIAxes.YLimMode = 'auto';
            app.UIAxes.ZLimMode = 'auto';
        end

        % Button pushed function: OptimizeBetaAnglesButton
        function OptimizeBetaAnglesButtonPushed(app, event)
            % --- Step 1: Get Satellite Inertia ---
            I_body = app.data.initialValues.I;
            
            % Extract the principal moments of inertia from the diagonal of the matrix.
            Ixx = I_body(1,1); 
            Iyy = I_body(2,2); 
            Izz = I_body(3,3);
            
            % --- Step 2: Calculate Optimal Angles ---
            % Calculate the optimal angles directly using the analytical solution
            % from Yoon et al. (2014), Eq. (9). This is more efficient and
            % accurate than numerical optimization.
            beta_1_rad = atan2(Iyy, Ixx); % Use atan2 for quadrant-correct results.
            beta_2_rad = atan(Izz / sqrt(Ixx^2 + Iyy^2));
            
            % --- Step 3: Update UI and App State ---
            % Set the UI edit fields to the calculated optimal values in degrees.
            app.Beta1degEditField.Value = rad2deg(beta_1_rad);
            app.Beta2degEditField.Value = rad2deg(beta_2_rad);
            
            % This optimization is specific to a 4-wheel configuration.
            current_rw_count = 4;
            app.NumberofRWEditField.Value = current_rw_count; 
            
            % Get the maximum momentum per wheel from the UI.
            maxMomentum = app.MaxHNmsEditField.Value;
            
            % --- Step 4: Recalculate Orientation and Momentum Envelope ---
            % Update the wheel orientation matrix with the new optimal angles.
            app.data.rw.W_temp = app.calculate_W_matriz(current_rw_count, beta_2_rad, beta_1_rad);
            
            % Create a vector of maximum momentum values for each wheel.
            Hmax = ones(1, size(app.data.rw.W_temp, 2)) * maxMomentum;
            
            % Calculate the maximum slew rate achievable in the worst-case direction
            % using the formula from Yoon et al. (2014), Eq. (14).
            omega_max_min = (2 * sqrt(2) * maxMomentum) / sqrt(Ixx^2 + Iyy^2 + Izz^2);
        
            % Get the corners and facet normals of the momentum envelope.
            [corners, nij] = app.get_momentum_envelope_corners(Hmax, app.data.rw.W_temp);
        
            % --- Step 5: Update the Plot ---
            % Clear the axes to prepare for the new plot.
            cla(app.UIAxes);   
            
            % Plot the inscribed momentum ellipsoid for the calculated worst-case slew rate.
            app.plotSatelliteMomentumElipsoid(app.data, app.UIAxes, omega_max_min);
            
            % Hold the plot to overlay additional graphics.
            hold(app.UIAxes, 'on');
            
            % Plot the new reaction wheel axes.
            app.plotWmatrix(app.UIAxes, app.data.rw.W_temp);
            
            % Plot the full momentum envelope.
            app.plotEnvelopment(nij, corners);
            
            % Release the hold on the plot.
            hold(app.UIAxes, 'off');
            app.UIAxes.XLimMode = 'auto';
            app.UIAxes.YLimMode = 'auto';
            app.UIAxes.ZLimMode = 'auto';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Reaction Wheel Configuration')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-1 1];
            app.UIAxes.YLim = [-1 1];
            app.UIAxes.ZLim = [-1 1];
            app.PlotTorqueEnvelopeButton = uibutton(app.GridLayout, 'push');
            app.PlotTorqueEnvelopeButton.ButtonPushedFcn = createCallbackFcn(app, @PlotTorqueEnvelopeButtonPushed, true);
            app.PlotTorqueEnvelopeButton.Text = 'Plot Torque Envelope';
            app.ReactionWheelsParametersPanel = uipanel(app.GridLayout);
            app.ReactionWheelsParametersPanel.Title = 'Reaction Wheels Parameters';
            app.GridLayout2 = uigridlayout(app.ReactionWheelsParametersPanel);
            app.NumberofRWLabel = uilabel(app.GridLayout2);
            app.NumberofRWLabel.Text = 'Number of RW:';
            app.NumberofRWEditField = uieditfield(app.GridLayout2, 'numeric');
            app.NumberofRWEditField.Limits = [3 4];
            app.NumberofRWEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofRWEditFieldValueChanged, true);
            app.NumberofRWEditField.Value = 3;
            app.Beta1degEditFieldLabel = uilabel(app.GridLayout2);
            app.Beta1degEditFieldLabel.Text = 'Beta 1 (deg):';
            app.Beta1degEditField = uieditfield(app.GridLayout2, 'numeric');
            app.Beta1degEditField.ValueChangedFcn = createCallbackFcn(app, @Beta1degEditFieldValueChanged, true);
            app.Beta2degEditFieldLabel = uilabel(app.GridLayout2);
            app.Beta2degEditFieldLabel.Text = 'Beta 2 (deg):';
            app.Beta2degEditField = uieditfield(app.GridLayout2, 'numeric');
            app.Beta2degEditField.ValueChangedFcn = createCallbackFcn(app, @Beta2degEditFieldValueChanged, true);
            app.ResetButton = uibutton(app.GridLayout2, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.SaveButton_2 = uibutton(app.GridLayout2, 'push');
            app.SaveButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_2Pushed, true);
            app.SaveButton_2.Text = 'Save';
            app.MaxHLabel = uilabel(app.GridLayout2);
            app.MaxHLabel.Text = 'Max H (Nms):';
            app.MaxHNmsEditField = uieditfield(app.GridLayout2, 'numeric');
            app.PlotSatellieInertiaElipsoidButton = uibutton(app.GridLayout, 'push');
            app.PlotSatellieInertiaElipsoidButton.ButtonPushedFcn = createCallbackFcn(app, @PlotSatellieInertiaElipsoidButtonPushed, true);
            app.PlotSatellieInertiaElipsoidButton.Text = 'Plot Satellie Inertia Elipsoid';
            app.OptimizeBetaAnglesButton = uibutton(app.GridLayout, 'push');
            app.OptimizeBetaAnglesButton.ButtonPushedFcn = createCallbackFcn(app, @OptimizeBetaAnglesButtonPushed, true);
            app.OptimizeBetaAnglesButton.Text = 'Optimize Beta Angles';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = reactionWheelsConf(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end