classdef sensorsSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        ApplyButton_2                matlab.ui.control.StateButton
        GyroscopePanel               matlab.ui.container.Panel
        GridLayout2_4                matlab.ui.container.GridLayout
        gyro_bias_z                  matlab.ui.control.NumericEditField
        gyro_bias_y                  matlab.ui.control.NumericEditField
        gyro_bias_x                  matlab.ui.control.NumericEditField
        ZLabel_8                     matlab.ui.control.Label
        gyro_std_z                   matlab.ui.control.NumericEditField
        gyro_std_y                   matlab.ui.control.NumericEditField
        gyro_std_x                   matlab.ui.control.NumericEditField
        ZLabel_7                     matlab.ui.control.Label
        YLabel_4                     matlab.ui.control.Label
        XLabel_4                     matlab.ui.control.Label
        BiasLabel_4                  matlab.ui.control.Label
        StandardDeviationLabel_4     matlab.ui.control.Label
        ApplyButton                  matlab.ui.control.Button
        StarSensorPanel              matlab.ui.container.Panel
        GridLayout2_3                matlab.ui.container.GridLayout
        Enable_Stars                 matlab.ui.control.CheckBox
        NumberofstarsEditField       matlab.ui.control.NumericEditField
        NumberofstarsEditFieldLabel  matlab.ui.control.Label
        star_bias_z                  matlab.ui.control.NumericEditField
        star_bias_y                  matlab.ui.control.NumericEditField
        star_bias_x                  matlab.ui.control.NumericEditField
        ZLabel_6                     matlab.ui.control.Label
        star_std_z                   matlab.ui.control.NumericEditField
        star_std_y                   matlab.ui.control.NumericEditField
        star_std_x                   matlab.ui.control.NumericEditField
        ZLabel_5                     matlab.ui.control.Label
        YLabel_3                     matlab.ui.control.Label
        XLabel_3                     matlab.ui.control.Label
        BiasLabel_3                  matlab.ui.control.Label
        StandardDeviationLabel_3     matlab.ui.control.Label
        MagnetometerPanel            matlab.ui.container.Panel
        GridLayout2_2                matlab.ui.container.GridLayout
        mag_bias_z                   matlab.ui.control.NumericEditField
        mag_bias_y                   matlab.ui.control.NumericEditField
        mag_bias_x                   matlab.ui.control.NumericEditField
        ZLabel_4                     matlab.ui.control.Label
        mag_std_z                    matlab.ui.control.NumericEditField
        mag_std_y                    matlab.ui.control.NumericEditField
        mag_std_x                    matlab.ui.control.NumericEditField
        ZLabel_3                     matlab.ui.control.Label
        YLabel_2                     matlab.ui.control.Label
        XLabel_2                     matlab.ui.control.Label
        BiasLabel_2                  matlab.ui.control.Label
        StandardDeviationLabel_2     matlab.ui.control.Label
        AccelerometerPanel           matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        acc_bias_z                   matlab.ui.control.NumericEditField
        acc_bias_y                   matlab.ui.control.NumericEditField
        acc_bias_x                   matlab.ui.control.NumericEditField
        ZLabel_2                     matlab.ui.control.Label
        acc_std_z                    matlab.ui.control.NumericEditField
        acc_std_y                    matlab.ui.control.NumericEditField
        acc_std_x                    matlab.ui.control.NumericEditField
        ZLabel                       matlab.ui.control.Label
        YLabel                       matlab.ui.control.Label
        XLabel                       matlab.ui.control.Label
        BiasLabel                    matlab.ui.control.Label
        StandardDeviationLabel       matlab.ui.control.Label
        SensorModelsParametersLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        callMainApp % Description
    end
    
    methods (Access = private)
        
        function updateInterface(app, data)
            %%%Update gyro data
            app.gyro_std_x.Value  = data.gyro.std(1);
            app.gyro_std_y.Value  = data.gyro.std(2);
            app.gyro_std_z.Value  = data.gyro.std(3);
            app.gyro_bias_x.Value = data.gyro.bias(1);
            app.gyro_bias_y.Value = data.gyro.bias(2);
            app.gyro_bias_z.Value = data.gyro.bias(3);
            %%%Update acc data
            app.acc_std_x.Value  = data.acc.std(1);
            app.acc_std_y.Value  = data.acc.std(2);
            app.acc_std_z.Value  = data.acc.std(3);
            app.acc_bias_x.Value = data.acc.bias(1);
            app.acc_bias_y.Value = data.acc.bias(2);
            app.acc_bias_z.Value = data.acc.bias(3);
            %%%Update mag data
            app.mag_std_x.Value  = data.mag.std(1);
            app.mag_std_y.Value  = data.mag.std(2);
            app.mag_std_z.Value  = data.mag.std(3);
            app.mag_bias_x.Value = data.mag.bias(1);
            app.mag_bias_y.Value = data.mag.bias(2);
            app.mag_bias_z.Value = data.mag.bias(3);
            %%%Update stars data
            app.star_std_x.Value  = data.star.std(1);
            app.star_std_y.Value  = data.star.std(2);
            app.star_std_z.Value  = data.star.std(3);
            app.star_bias_x.Value = data.star.bias(1);
            app.star_bias_y.Value = data.star.bias(2);
            app.star_bias_z.Value = data.star.bias(3);
            app.Enable_Stars.Value = data.star.enable;
            app.NumberofstarsEditField.Value = data.star.numberOfStars;

            value = app.Enable_Stars.Value;
            if value == 0
                app.star_std_x.Editable = "off";
                app.star_std_y.Editable = "off";
                app.star_std_z.Editable = "off";
                app.star_bias_x.Editable = "off";
                app.star_bias_y.Editable = "off";
                app.star_bias_z.Editable = "off";
                app.NumberofstarsEditField.Editable = "off";
            else
                app.star_std_x.Editable = "on";
                app.star_std_y.Editable = "on";
                app.star_std_z.Editable = "on";
                app.star_bias_x.Editable = "on";
                app.star_bias_y.Editable = "on";
                app.star_bias_z.Editable = "on";
                app.NumberofstarsEditField.Editable = "on";
            end
        end    
        
        function newData = updateParameters(app)
            %%% Update gyro data
            newData.gyro.std(1) = app.gyro_std_x.Value;
            newData.gyro.std(2) = app.gyro_std_y.Value;
            newData.gyro.std(3) = app.gyro_std_z.Value;
            newData.gyro.bias(1) = app.gyro_bias_x.Value;
            newData.gyro.bias(2) = app.gyro_bias_y.Value;
            newData.gyro.bias(3) = app.gyro_bias_z.Value;
            
            %%% Update acc data
            newData.acc.std(1) = app.acc_std_x.Value;
            newData.acc.std(2) = app.acc_std_y.Value;
            newData.acc.std(3) = app.acc_std_z.Value;
            newData.acc.bias(1) = app.acc_bias_x.Value;
            newData.acc.bias(2) = app.acc_bias_y.Value;
            newData.acc.bias(3) = app.acc_bias_z.Value;
            
            %%% Update mag data
            newData.mag.std(1) = app.mag_std_x.Value;
            newData.mag.std(2) = app.mag_std_y.Value;
            newData.mag.std(3) = app.mag_std_z.Value;
            newData.mag.bias(1) = app.mag_bias_x.Value;
            newData.mag.bias(2) = app.mag_bias_y.Value;
            newData.mag.bias(3) = app.mag_bias_z.Value;
            
            %%% Update stars data
            newData.star.std(1) = app.star_std_x.Value;
            newData.star.std(2) = app.star_std_y.Value;
            newData.star.std(3) = app.star_std_z.Value;
            newData.star.bias(1) = app.star_bias_x.Value;
            newData.star.bias(2) = app.star_bias_y.Value;
            newData.star.bias(3) = app.star_bias_z.Value;
            newData.star.enable = app.Enable_Stars.Value;
            newData.star.numberOfStars = app.NumberofstarsEditField.Value;

            newData.i = 3;
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, mainAppData)
            app.callMainApp = mainApp;
            updateInterface(app, mainAppData);
        end

        % Value changed function: Enable_Stars
        function Enable_StarsValueChanged(app, event)
            value = app.Enable_Stars.Value;
            if value == 0
                app.star_std_x.Editable = "off";
                app.star_std_y.Editable = "off";
                app.star_std_z.Editable = "off";
                app.star_bias_x.Editable = "off";
                app.star_bias_y.Editable = "off";
                app.star_bias_z.Editable = "off";
                app.NumberofstarsEditField.Editable = "off";
            else
                app.star_std_x.Editable = "on";
                app.star_std_y.Editable = "on";
                app.star_std_z.Editable = "on";
                app.star_bias_x.Editable = "on";
                app.star_bias_y.Editable = "on";
                app.star_bias_z.Editable = "on";
                app.NumberofstarsEditField.Editable = "on";
            end
        end

        % Value changed function: ApplyButton_2
        function ApplyButton_2ValueChanged(app, event)
            %value = app.ApplyButton_2.Value;
            dataToSend = updateParameters(app);
            importData(app.callMainApp, dataToSend);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.SensorModelsParametersLabel = uilabel(app.GridLayout);
            app.SensorModelsParametersLabel.Text = 'Sensor Models Parameters';
            app.AccelerometerPanel = uipanel(app.GridLayout);
            app.AccelerometerPanel.Title = 'Accelerometer';
            app.GridLayout2 = uigridlayout(app.AccelerometerPanel);
            app.StandardDeviationLabel = uilabel(app.GridLayout2);
            app.StandardDeviationLabel.Text = 'Standard Deviation:';
            app.BiasLabel = uilabel(app.GridLayout2);
            app.BiasLabel.Text = 'Bias:';
            app.XLabel = uilabel(app.GridLayout2);
            app.XLabel.Text = 'X';
            app.YLabel = uilabel(app.GridLayout2);
            app.YLabel.Text = 'Y';
            app.ZLabel = uilabel(app.GridLayout2);
            app.ZLabel.Text = 'Z';
            app.acc_std_x = uieditfield(app.GridLayout2, 'numeric');
            app.acc_std_x.Limits = [0 Inf];
            app.acc_std_y = uieditfield(app.GridLayout2, 'numeric');
            app.acc_std_y.Limits = [0 Inf];
            app.acc_std_z = uieditfield(app.GridLayout2, 'numeric');
            app.acc_std_z.Limits = [0 Inf];
            app.ZLabel_2 = uilabel(app.GridLayout2);
            app.ZLabel_2.Text = 'Z';
            app.acc_bias_x = uieditfield(app.GridLayout2, 'numeric');
            app.acc_bias_y = uieditfield(app.GridLayout2, 'numeric');
            app.acc_bias_z = uieditfield(app.GridLayout2, 'numeric');
            app.MagnetometerPanel = uipanel(app.GridLayout);
            app.MagnetometerPanel.Title = 'Magnetometer';
            app.GridLayout2_2 = uigridlayout(app.MagnetometerPanel);
            app.StandardDeviationLabel_2 = uilabel(app.GridLayout2_2);
            app.StandardDeviationLabel_2.Text = 'Standard Deviation:';
            app.BiasLabel_2 = uilabel(app.GridLayout2_2);
            app.BiasLabel_2.Text = 'Bias:';
            app.XLabel_2 = uilabel(app.GridLayout2_2);
            app.XLabel_2.Text = 'X';
            app.YLabel_2 = uilabel(app.GridLayout2_2);
            app.YLabel_2.Text = 'Y';
            app.ZLabel_3 = uilabel(app.GridLayout2_2);
            app.ZLabel_3.Text = 'Z';
            app.mag_std_x = uieditfield(app.GridLayout2_2, 'numeric');
            app.mag_std_x.Limits = [0 Inf];
            app.mag_std_y = uieditfield(app.GridLayout2_2, 'numeric');
            app.mag_std_y.Limits = [0 Inf];
            app.mag_std_z = uieditfield(app.GridLayout2_2, 'numeric');
            app.mag_std_z.Limits = [0 Inf];
            app.ZLabel_4 = uilabel(app.GridLayout2_2);
            app.ZLabel_4.Text = 'Z';
            app.mag_bias_x = uieditfield(app.GridLayout2_2, 'numeric');
            app.mag_bias_y = uieditfield(app.GridLayout2_2, 'numeric');
            app.mag_bias_z = uieditfield(app.GridLayout2_2, 'numeric');
            app.StarSensorPanel = uipanel(app.GridLayout);
            app.StarSensorPanel.Title = 'Star Sensor';
            app.GridLayout2_3 = uigridlayout(app.StarSensorPanel);
            app.StandardDeviationLabel_3 = uilabel(app.GridLayout2_3);
            app.StandardDeviationLabel_3.Text = 'Standard Deviation:';
            app.BiasLabel_3 = uilabel(app.GridLayout2_3);
            app.BiasLabel_3.Text = 'Bias:';
            app.XLabel_3 = uilabel(app.GridLayout2_3);
            app.XLabel_3.Text = 'X';
            app.YLabel_3 = uilabel(app.GridLayout2_3);
            app.YLabel_3.Text = 'Y';
            app.ZLabel_5 = uilabel(app.GridLayout2_3);
            app.ZLabel_5.Text = 'Z';
            app.star_std_x = uieditfield(app.GridLayout2_3, 'numeric');
            app.star_std_x.Limits = [0 Inf];
            app.star_std_y = uieditfield(app.GridLayout2_3, 'numeric');
            app.star_std_y.Limits = [0 Inf];
            app.star_std_z = uieditfield(app.GridLayout2_3, 'numeric');
            app.star_std_z.Limits = [0 Inf];
            app.ZLabel_6 = uilabel(app.GridLayout2_3);
            app.ZLabel_6.Text = 'Z';
            app.star_bias_x = uieditfield(app.GridLayout2_3, 'numeric');
            app.star_bias_y = uieditfield(app.GridLayout2_3, 'numeric');
            app.star_bias_z = uieditfield(app.GridLayout2_3, 'numeric');
            app.NumberofstarsEditFieldLabel = uilabel(app.GridLayout2_3);
            app.NumberofstarsEditFieldLabel.Text = 'Number of stars:';
            app.NumberofstarsEditField = uieditfield(app.GridLayout2_3, 'numeric');
            app.NumberofstarsEditField.Limits = [0 Inf];
            app.Enable_Stars = uicheckbox(app.GridLayout2_3);
            app.Enable_Stars.ValueChangedFcn = createCallbackFcn(app, @Enable_StarsValueChanged, true);
            app.Enable_Stars.Text = '   Enable';
            app.Enable_Stars.Value = true;
            app.ApplyButton = uibutton(app.GridLayout, 'push');
            app.ApplyButton.Text = 'Apply';
            app.GyroscopePanel = uipanel(app.GridLayout);
            app.GyroscopePanel.Title = 'Gyroscope';
            app.GridLayout2_4 = uigridlayout(app.GyroscopePanel);
            app.StandardDeviationLabel_4 = uilabel(app.GridLayout2_4);
            app.StandardDeviationLabel_4.Text = 'Standard Deviation:';
            app.BiasLabel_4 = uilabel(app.GridLayout2_4);
            app.BiasLabel_4.Text = 'Bias:';
            app.XLabel_4 = uilabel(app.GridLayout2_4);
            app.XLabel_4.Text = 'X';
            app.YLabel_4 = uilabel(app.GridLayout2_4);
            app.YLabel_4.Text = 'Y';
            app.ZLabel_7 = uilabel(app.GridLayout2_4);
            app.ZLabel_7.Text = 'Z';
            app.gyro_std_x = uieditfield(app.GridLayout2_4, 'numeric');
            app.gyro_std_x.Limits = [0 Inf];
            app.gyro_std_y = uieditfield(app.GridLayout2_4, 'numeric');
            app.gyro_std_y.Limits = [0 Inf];
            app.gyro_std_z = uieditfield(app.GridLayout2_4, 'numeric');
            app.gyro_std_z.Limits = [0 Inf];
            app.ZLabel_8 = uilabel(app.GridLayout2_4);
            app.ZLabel_8.Text = 'Z';
            app.gyro_bias_x = uieditfield(app.GridLayout2_4, 'numeric');
            app.gyro_bias_y = uieditfield(app.GridLayout2_4, 'numeric');
            app.gyro_bias_z = uieditfield(app.GridLayout2_4, 'numeric');
            app.ApplyButton_2 = uibutton(app.GridLayout, 'state');
            app.ApplyButton_2.ValueChangedFcn = createCallbackFcn(app, @ApplyButton_2ValueChanged, true);
            app.ApplyButton_2.Text = 'Apply';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sensorsSettings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end